classdef TestLayout < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DriveawayfromobjectButton       matlab.ui.control.Button
        EmergencystopButton             matlab.ui.control.Button
        LogsTextArea                    matlab.ui.control.TextArea
        LogsTextAreaLabel               matlab.ui.control.Label
        ManualControlPanel              matlab.ui.container.Panel
        Panel                           matlab.ui.container.Panel
        tcpCordZ                        matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        ZLabel_2                        matlab.ui.control.Label
        TCPcoordinatesLabel             matlab.ui.control.Label
        tcpCordY                        matlab.ui.control.Label
        tcpCordX                        matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        YLabel_2                        matlab.ui.control.Label
        XLabel_2                        matlab.ui.control.Label
        StepsizeSpinner                 matlab.ui.control.Spinner
        StepsizeSpinnerLabel            matlab.ui.control.Label
        ZLabel                          matlab.ui.control.Label
        YLabel                          matlab.ui.control.Label
        XLabel                          matlab.ui.control.Label
        ManualYLeftBtn                  matlab.ui.control.Button
        manualYRightBtn                 matlab.ui.control.Button
        MinimumdistancetoobjectSpinner  matlab.ui.control.Spinner
        MinimumdistancetoobjectSpinnerLabel  matlab.ui.control.Label
        CoordinateSystemDropDown        matlab.ui.control.DropDown
        CoordinateSystemDropDownLabel   matlab.ui.control.Label
        ManualZDownBtn                  matlab.ui.control.Button
        manualXRightBtn                 matlab.ui.control.Button
        ManualXLeftBtn                  matlab.ui.control.Button
        ManualZUpBtn                    matlab.ui.control.Button
        AutomaticControlPanel           matlab.ui.container.Panel
        MicrowavescanningsettingsPanel  matlab.ui.container.Panel
        previewscanningpointsButton     matlab.ui.control.Button
        ScanningpointselectionPanel     matlab.ui.container.Panel
        ClearallpointsButton            matlab.ui.control.Button
        SelectpointsButton              matlab.ui.control.StateButton
        UITable                         matlab.ui.control.Table
        ScanningpointgenerationPanel    matlab.ui.container.Panel
        pointsLabel                     matlab.ui.control.Label
        nPointsValueLabel               matlab.ui.control.Label
        PointdensitySlider              matlab.ui.control.Slider
        PointdensitySliderLabel         matlab.ui.control.Label
        VNAPanel                        matlab.ui.container.Panel
        StartmicroscanButton            matlab.ui.control.Button
        ScanmodeButtonGroup             matlab.ui.container.ButtonGroup
        DistancetoobjectSpinner         matlab.ui.control.Spinner
        DistancetoobjectSpinnerLabel    matlab.ui.control.Label
        ScanselectionofpointsButton     matlab.ui.control.ToggleButton
        ScanareaButton                  matlab.ui.control.ToggleButton
        ScanwholeobjectButton           matlab.ui.control.ToggleButton
        DmodelreconstructionPanel       matlab.ui.container.Panel
        connecttorobotButton            matlab.ui.control.Button
        DatahandelingButtonGroup        matlab.ui.container.ButtonGroup
        ComplimentdataButton            matlab.ui.control.ToggleButton
        ReplacedataButton               matlab.ui.control.ToggleButton
        ScanningprotocolDropDown        matlab.ui.control.DropDown
        ScanningprotocolDropDownLabel   matlab.ui.control.Label
        ScanmodelButton                 matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)

        objPoints;
        shape;
        wantedTCPplotPos;
        TCPplotPos;
        diffRLPlotX;
        diffRLPlotY;
        diffRLPlotZ;
        testingCoord;
        connection;
        currentPose;
        currentDeg;
        waitingOnPos;
        f;
        normals;
        centerPoints;
        dirArrow;
        dataCursor;
        areaBoundary;
        surfaceInterpolation;
        existingPointPos;
        selectedPoints; % Description
        generatedPoints % Description
        scanningPointPreviewPlot;
        testPlot;
    end
    
    methods (Access = private)
       
        function status = connectToYumi(app)
            %connect to server through ip (TCP)
            writeToLog(app, "Connect to robot: Starting connection through TCP.");
            app.connection = tcpclient('192.168.125.1',55000);
            if app.connection.Status == "open"
                status = 1;
                writeToLog(app, "Connect to robot: TCP connection to robot successful.");
            else
                status = 0;
                writeToLog(app, "Connect to robot: TCP connection to robot failed.");
            end
        end

        function [pos, rot] = ReceiveAndUnpackPose(app, t)
            app.waitingOnPos = 1;
            %wait until message is received
            noMsgReceived = 1;
            while noMsgReceived
                receivedMsg = char(read(t));
                noMsgReceived = isempty(receivedMsg);
                %pause(0.5);
            end
            %Handle message string so that it becoms seperate rot och pos arrays
            match = ["[","]"];
            pose = split(receivedMsg, match);
            pos = pose(2);
            pos = split(pos,",");
            pos = str2double(pos);

            while noMsgReceived
                receivedMsg = char(read(t));
                noMsgReceived = isempty(receivedMsg);
                %pause(0.5);
            end
            match = ["[","]"];
            pose = split(receivedMsg, match);
            rot = pose(2);
            rot = split(rot, ",");
            rot = str2double(rot);

            %eulerRad = quat2eul(rot');
            %eulerDeg = rad2deg(eulerRad);
            app.waitingOnPos = 0;
        end

       function packAndSendPose(app, pos, eulerDeg, t)
           %eulerRad = deg2rad(eulerDeg);
           %eulerDeg = [0,0,0];

           %rot = eul2quat(eulerRad)';

           %put together msg string
           sPos = string(pos);
           sRot = string(eulerDeg);
          

           %add formatting to pos string
           sPos = join(sPos,",");
           sPos = strcat("[",sPos,"]");

           %add formatting to rot string
           sRot = join(sRot,",");
           sRot = strcat("[",sRot,"]");
           write(t,sPos);
           write(t, sRot);

           %put pos and rot together
           %sendMsg = strcat(sPos,",",sRot);
           %sendMsg = strcat("[",sendMsg,"]");

           %send msg string
           %write(t,sendMsg)
       end


       function results = testIfStepAllowed(app, newCoord)
           %tests to see if requested step in manual control is allowed by
           %seeing if the robot is currently moving, adding the set minimum
           %distance to movement on a vector pointing towards the center of
           %the object.
           if app.waitingOnPos
               results = 0;
               writeToLog(app, 'Movement denied: Robot is still moving, please wait...')
               return

           end
%            testCoord = newCoord;
% 
%            if newCoord(1) > 0
%                testCoord(1) = newCoord(1) - app.MinimumdistancetoobjectSpinner.Value;
%            else
%                testCoord(1) = newCoord(1) + app.MinimumdistancetoobjectSpinner.Value;
%            end
% 
%            if newCoord(2) > 0
%                testCoord(2) = newCoord(2) - app.MinimumdistancetoobjectSpinner.Value;
%            else
%                testCoord(2) = newCoord(2) + app.MinimumdistancetoobjectSpinner.Value;
%            end
% 
%            if newCoord(3) > 0
%                testCoord(3) = newCoord(3) - app.MinimumdistancetoobjectSpinner.Value;
%            else
%                testCoord(3) = newCoord(3) + app.MinimumdistancetoobjectSpinner.Value;
%            end

%            if isempty(app.testingCoord)
%                app.testingCoord = plot3(app.UIAxes, testCoord(1), testCoord(2), testCoord(3),'.','MarkerSize',10, 'Color','cyan');
%            else
%                app.testingCoord.XData = testCoord(1);
%                app.testingCoord.YData = testCoord(2);
%                app.testingCoord.ZData = testCoord(3);
% 
%            end

           angleFromX = atan2(sqrt(newCoord(2)^2+newCoord(3)^2),newCoord(1));
           angleFromY = atan2(sqrt(newCoord(3)^2+newCoord(1)^2),newCoord(2));
           angleFromZ = atan2(sqrt(newCoord(1)^2+newCoord(2)^2),newCoord(3));

           %Get length of vector from origo
           lenFromOrigo = sqrt(newCoord(1)^2+newCoord(2)^2+newCoord(3)^2);

           %Calculate new vector based on angles and new length

           newLength = lenFromOrigo - app.MinimumdistancetoobjectSpinner.Value;

           %if angleFromX <= ... then sin else cos
           newShorterVector(1) = newLength * cos(angleFromX);
           newShorterVector(2) = newLength * cos(angleFromY);
           newShorterVector(3) = newLength * cos(angleFromZ);

           newShorterVectorNoZ(1) = newShorterVector(1);
           newShorterVectorNoZ(2) = newShorterVector(2);
           newShorterVectorNoZ(3) = newCoord(3);

           %app.testingCoord = plot3(app.UIAxes, newShorterVector(1), newShorterVector(2), newShorterVector(3),'.','MarkerSize',10, 'Color','cyan');


           in = inShape(app.shape, newShorterVector) | inShape(app.shape, newShorterVectorNoZ);
           results = ~in;
           if in
               writeToLog(app, 'Movement denied: New coordinate would be in object or within minimum distance set to object.');
           end

       end

       function updateTCPcoordAndAngle(app)
           %Sends pose to robot if the connection is open and updates all
           %gui text displaying coordinate values
           [tTest, ~, zTest] = cart2pol(app.wantedTCPplotPos.XData,app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData);
            newPointR = app.surfaceInterpolation(tTest, zTest);
            [nx, ny,nz] = pol2cart(tTest, newPointR, zTest);
            %plot3(app.UIAxes, nx, ny, nz,'.','MarkerSize',10, 'Color','cyan');


           if ~isempty(app.wantedTCPplotPos)
               [angle_H, angle_P, angle_B] = calcAngles(app);

%                 if checkConnectionStatus(app)
%                     newRealPose = transPlottoRL(app, app.wantedTCPplotPos.XData, app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData)';
%                     packAndSendPose(app, newRealPose, [angle_B, angle_H, angle_P], app.connection);
%                 end
           end


           if app.CoordinateSystemDropDown.Value == "Carteasian"
               app.tcpCordX.Text = string(app.wantedTCPplotPos.XData);
               app.tcpCordY.Text = string(app.wantedTCPplotPos.YData);
               app.tcpCordZ.Text = string(app.wantedTCPplotPos.ZData);

           elseif app.CoordinateSystemDropDown.Value == "Cylindrical"
               [theta, r, z] = cart2pol(app.wantedTCPplotPos.XData,app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData);
               app.tcpCordX.Text = string(r);
               app.tcpCordY.Text = string(theta);
               app.tcpCordZ.Text = string(z);

           elseif app.CoordinateSystemDropDown.Value == "Spherical"
               [theta, phi, rho] = cart2sph(app.wantedTCPplotPos.XData,app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData);
               app.tcpCordX.Text = string(phi);
               app.tcpCordY.Text = string(theta);
               app.tcpCordZ.Text = string(rho);


           end


       end

       function newP = transPlottoRL(app, pX, pY, pZ)
           %translates plot's coordinate system to the robot coordinate
           %system by subtracting the saved difference from translating to plot coordinates.
           newP(:, 1) = pX - app.diffRLPlotX;
           newP(:, 2) = pY - app.diffRLPlotY;
           newP(:, 3) = pZ - app.diffRLPlotZ;


       end

       function  newP = transRLtoPlot(app, P)
           %translates the robot coordinate system to plot coordinate
           %system, so that the model's center always is in origo (0,0,0)
           if isempty(app.diffRLPlotX)
               centerX = mean(P(:, 1));
               centerY = mean(P(:, 2));
               centerZ = mean(P(:, 3));

               app.diffRLPlotX = -centerX;
               app.diffRLPlotY = -centerY;
               app.diffRLPlotZ = -centerZ;

           end

           newP(:, 1) = P(:,1) + app.diffRLPlotX;
           newP(:, 2) = P(:,2) + app.diffRLPlotY;
           newP(:, 3) = P(:,3) + app.diffRLPlotZ;

       end


       function writeToLog(app, text)
           %get current datetime for logs
           t = now;
           d = string(datetime(t,'ConvertFrom','datenum'));
           test = string(app.LogsTextArea.Value);
           newText = d + ": " + text; %create new text row
           app.LogsTextArea.Value = vertcat(test, newText); %adds text row to text log window
           scroll(app.LogsTextArea, 'bottom'); %scrolls log window to bottom
       end

       function status = checkConnectionStatus(app)
           if app.connection.Status == "open"
               status = 1;
           else
               status = 0;
               writeToLog(app, "Connection to robot lost.");
           end

       end

       function connected = getAndPlotTCPpos(app)
           while true
               connected = checkConnectionStatus(app);
               if connected %when connected, receive and plot actual tcp position in the plot coordinate scale
                   [pos, eulerDeg] = ReceiveAndUnpackPose(app, app.connection);
                   plotPos = transRLtoPlot(app, pos);
                   if ~isempty(app.TCPplotPos) %if there is no current plot for tcp pos plot it
                       app.TCPplotPos = plot3(app.UIAxes, plotPos(1), plotPos(2), plotPos(3),'.','MarkerSize',100, 'Color','Blue');
                   else %otherwise update its coordinates
                       app.TCPplotPos.XData = plotPos(1);
                       app.TCPplotPos.YData = plotPos(2);
                       app.TCPplotPos.ZData = plotPos(3);
                   end

                   %signal to other functions if actual tcp pos matches the
                   %wanted tcp pos.
                   if app.TCPplotPos.XData == app.wantedTCPplotPos.XData && app.TCPplotPos.YData == app.wantedTCPplotPos.YData && app.TCPplotPos.ZData == app.wantedTCPplotPos.ZData
                       app.waitingOnPos = 0;
                   else
                       app.waitingOnPos = 1;
                   end
               else
                   cancel(app.f); %terminate itself if connection is lost
                   return
               end
               pause(0.5); %to not flood the computer
           end

       end
        
       function [angle_H, angle_P, angle_B] = calcAngles(app)
           %get the current position
           pos = [app.wantedTCPplotPos.XData, app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData];
           center = app.centerPoints;
           %search for closest triangle center point (gives index of point)
           [k,dist] = dsearchn(center,pos);
           %plot normal, origin from tcp point
           if isempty(app.dirArrow) %if not plotted before, plot, otherwise update coordinates
               app.dirArrow = quiver3(app.wantedTCPplotPos.XData, app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData, app.normals(k,1), app.normals(k,2), app.normals(k,3), 'Parent', app.UIAxes, 'AutoScale','on', 'AutoScaleFactor',15, 'LineWidth',1.5, 'MaxHeadSize',20);
           else
               app.dirArrow.XData = app.wantedTCPplotPos.XData;
               app.dirArrow.YData = app.wantedTCPplotPos.YData;
               app.dirArrow.ZData = app.wantedTCPplotPos.ZData;

               app.dirArrow.UData = app.normals(k,1);
               app.dirArrow.VData = app.normals(k,2);
               app.dirArrow.WData = app.normals(k,3);
           end
           %get the corresponding normal (use index given from the nearest
           %point search)
           sNormal = app.normals(k,:);
           U = [0,0,1]; %up vector
           angle_H = rad2deg(atan2(sNormal(2), sNormal(1))); %calculate heading angle
           angle_P = rad2deg(asin(sNormal(3))); %calculate pitch angle

           W0 = [-sNormal(2), sNormal(1), 0 ];
           U0 = cross(W0, sNormal);
           angle_B = rad2deg(atan2( dot(W0,U) / norm(W0), dot(U0,U) / norm(U0) )); %calculate bank angle
           if ismissing(angle_B)
               angle_B = 0;
           elseif ismissing(angle_H)
               angle_H = 0;
           elseif ismissing(angle_P)
               angle_P = 0;
           end

       end
        
       function getFaceNormals(app, shp)
           %get triangle connectivity
           [tri, xyz] = boundaryFacets(shp);
           %split columns into points
           p1 = tri(:, 1);
           p2 = tri(:, 2);
           p3 = tri(:, 3);
           %go through each triangle and get the normals
           for i = 1:length(tri)
               trianglePoints(1, :) = xyz(p1(i), :);
               trianglePoints(2, :) = xyz(p2(i), :);
               trianglePoints(3, :) = xyz(p3(i), :);
               app.centerPoints(i, :) = mean(trianglePoints); %get triangle center point
               normal(i, :) = cross(trianglePoints(1,:)-trianglePoints(2,:), trianglePoints(1,:)-trianglePoints(3, :));
               app.normals(i, :) = -(normal(i, :) / norm( normal(i, :) )); % just to make it unit length
           end
       end

       function txt = updatePoints(app, obj, event_obj, ax)
           pos = get(event_obj, 'Position');
           doesNotExist = true;
           oldTableData = get(app.UITable,'Data');
           existingPointPos = zeros(numel(app.selectedPoints), 3);
           for i=1:numel(app.selectedPoints)
               existingPointPos(i, :) = [app.selectedPoints(i).XData app.selectedPoints(i).YData app.selectedPoints(i).ZData];
               n = existingPointPos(i, :);
               if isequal(pos, n)
                   doesNotExist = false;
                   delete(app.selectedPoints(i));
                   app.selectedPoints(i) = [];
                   oldTableData(i, :) = [];
                   newTableData = oldTableData;
                   existingPointPos(i, :) = [];

                   break
               end

           end
           if doesNotExist
               point = plot3(ax, pos(1), pos(2), pos(3), ...
                   '-o','Color','r','MarkerSize',3,...
                   'MarkerFaceColor','r');
               app.selectedPoints = [app.selectedPoints point];
               newTableData = [oldTableData; [height(oldTableData)+1 pos]];
               existingPointPos = [existingPointPos ; pos];
           end

           set(app.UITable, 'Data', newTableData);

           if app.ScanmodeButtonGroup.SelectedObject == app.ScanareaButton

               k = boundary(existingPointPos);
               if ~isempty(app.areaBoundary)
                   delete(app.areaBoundary);
               end
               app.areaBoundary = trisurf(k, existingPointPos(:,1), existingPointPos(:,2), existingPointPos(:,3), 'Parent', app.UIAxes, 'FaceColor', 'blue');

           end

           app.existingPointPos = existingPointPos;


           %delete(app.selectedpoints);
           txt = '';
       end


       function txt = makeDataTipInvisible(app, pdt, ~)
           pdt.Visible = 'off';
           txt = '';
       end
        
       function pointList = generateScanningPoints(app, object, distanceThres)
           % Generates *almost* evenly spaced points along the surface of
           % the object. Spacing between points depends on variable
           % distanceThres, which is the distance the points should have
           % between each other and distanceThresTolerance which allows the
           % distance to vary within set interval since in some cases 
           % evenly spaced points are impossible. 
           
           % Points are generated along the surface by stepping around the
           % object in a cylindrical coordinate system, starting at
           % theta = 0 until theta is 0 again. The algorithm works from 
           % the top of the object to the bottom, decrementing the Z value 
           % everytime a round around the object is completed. The
           % projection onto the object surface (R value) is found through 
           % a interpolation algorithm. Distance between points are 
           % controlled by finding the closest point in the same row and 
           % the row above, and adjusting the point's position accordingly.
       

           %Editable parameters to affect algorithm behavior:
           %---------------------------
           %distanceThres = 1; %The ideal distance between all points
           distanceThresTolerance = 0.5; %How tolerant the distance should be: creates a interval from the distance threshold. Higher number = more accepted points
           adjustmentLimit = 1000; %Limit on the maximum amount of adjustemnts done to a single point. Higher value = longer execution time, but higher chance of better positioning
           stepSizeZ = 1; %Initial step taken in Z direction, should be a relativly low number. The program finds the exact stepsize for Z iterativly
           originalStepSizeTheta = 0.01; %The difference in theta for every step
           %---------------------------

           zMax = max(object.Points(:,3)); %Highest Z of the object
           zMin = min(object.Points(:,3)); %Lowest Z of the object
           stepSizeTheta = originalStepSizeTheta; %original and stepSizeTheta are seperate because stepsize can vary throught the generation
           distanceThresTolerance_c = distanceThresTolerance;
           pointsCell = cell(10); %pointsCell holds all the points generated. Each column represents a round around the object in the same Z level
           pointCellPol = cell(10);
           j = 1; %iterator
           pointList = [];
           thetaLimit = double(3.1416); %As the algorithm works in cylindrical coordinates, the sign of theta changes at pi
           sideSwitched = false; %Keeps track if the algorithm is currently working on the positive side or the negative side
           nAdjustments = 0; %Keeps track of the number of adjustments done to a single point's position
           zCurrent = zMax-5; %ZCurrent keeps track of the current Z level, algorithm starts at the top of the object

           %Algorithm runs until the bottom of the object is reached.
           % 1 iteration = 1 row done
           while zCurrent >= zMin
               i = 1;
               thetaCurrent = -stepSizeTheta; %the first point should lay on theta = 0, because theta is always incremented by steSizeTheta, give negative so theta becomes 0
               lastPointInRowDone = false; %keeps track of the last point of a row is completed
               lastPointInRow = false; %keeps track if the last point in the row is being worked on
               hoppedSide = false;

               %Loops while placing and adjusting points in a row. Is
               %exited when all points on the row are placed.
               while true
                   % the the number of adjustments for a single point 
                   % exceeds the limit, increase the distance tolerance 
                   % so that the point has higher chance to be accepted:
                   if nAdjustments > adjustmentLimit 
                       distanceThresTolerance_c = distanceThresTolerance_c + 0.1;
%                        if distanceThresTolerance_c > distanceThresTolerance * 5
%                            break;
%                        end
                   end
                   thetaCurrent = thetaCurrent + stepSizeTheta;

                   %Checks if the row is done by seeing if we have
                   %compleeted the last point in the row, theta has passed
                   %the first point and we are comming from the negative
                   %(theta) side of the object.
                   if thetaCurrent >= 0 && sideSwitched || lastPointInRowDone
                       sideSwitched = false; %resets the side indicator for the next row
                       break %exit the loop to begin a new row
                   end

                   %if the new theta passes pi, change the theta value so
                   %it can continue in the same direction:
                   if round(thetaCurrent*1000) >= round(thetaLimit*1000) %if alogorithm goes from positive to negative theta side
                       thetaCurrent = -3.1416 + (thetaCurrent-thetaLimit);
                       sideSwitched = true;
                   elseif round(thetaCurrent*1000) <= -round(thetaLimit*1000) %if algorithm goes from negative to positive theta side
                       thetaCurrent = 3.1416 - (thetaCurrent+thetaLimit);
                       sideSwitched = false;
                   elseif thetaCurrent < 0 %if algorithm works on negative theta side
                       sideSwitched = true;
                   end
                   newPoint = [thetaCurrent, app.surfaceInterpolation(thetaCurrent, zCurrent), zCurrent]; %create a new point in the current theta and z, r is decided by interpolation

                   if newPoint(2) > 500 || any(isnan(newPoint)) || any(isinf(newPoint))
                       continue
                   end
                   in = false;
                   o_R = newPoint(2);
                   while ~in
                       [newPointCart(1), newPointCart(2), newPointCart(3)] = pol2cart(newPoint(1), newPoint(2), newPoint(3)); %Convert from cylindtrical coordinates to carteasian to measure distance
%                        if ~isempty(app.testPlot)
%                            delete(app.testPlot);
%                        end
                      %app.testPlot = plot3(app.UIAxes, newPointCart(:, 1), newPointCart(:, 2), newPointCart(:, 3),'.','MarkerSize',10, 'Color','red');

                       in = inShape(object, newPointCart);
                       if ~in
                           newPoint(2) = newPoint(2) - 0.1;
                           
                       end
                       if newPoint(2) < 0
                           newPoint(2) = o_R;
                           in = true;
                       end

                   end
                   while in
                       [newPointCart(1), newPointCart(2), newPointCart(3)] = pol2cart(newPoint(1), newPoint(2), newPoint(3)); %Convert from cylindtrical coordinates to carteasian to measure distance
                       in = inShape(object, newPointCart);
                       if in
                           newPoint(2) = newPoint(2) + 0.1;
                       end
                   end

                   [newPointCart(1), newPointCart(2), newPointCart(3)] = pol2cart(newPoint(1), newPoint(2), newPoint(3)); %Convert from cylindtrical coordinates to carteasian to measure distance
                      
                   pointsCell(i, j) = {[]}; %allocate space for current iteration (so that algorithm never works out of bounds)
                   sameRowPoints = cell2mat(pointsCell(:, j)); %gets all points exsisting on the current row

                   if ~isempty(sameRowPoints) %if there is points on the row (this point is not the first point on the row)
                       [k,distance] = dsearchn(sameRowPoints, newPointCart); %search for the closest point in the same row and get the distance between the points
                   end

                   %if this point is the first point, or the point's
                   %position is withing the accepted distance to the
                   %closest point on the row:
                   if isempty(sameRowPoints) || distance >= distanceThres - distanceThresTolerance_c && distance <= distanceThres + distanceThresTolerance_c
                       % if this is not the first row, make an atempt to 
                       % adjust the Z value to achive an acceptable 
                       % distance between this point and the closest 
                       % point in the row above:   
                       if j > 1 || stepSizeTheta == 0
                           aboveRowPoints = cell2mat(pointsCell(:, j-1)); %gets a list of points exsisting in the row above the current working row
                           [~,dist] = dsearchn(aboveRowPoints, newPointCart); %searches for closest point to our current working point in the row above, gets the distance between them
                           %if the distance between the points are under
                           %the accepted minimum distance, and we have not
                           %yet reached the bottom of the object: move down
                           %on the object to increase the distance:
                           if dist < distanceThres - distanceThresTolerance_c && zCurrent - 0.05 > zMin
                               zCurrent = zCurrent - 0.05; %move down a little on the object
                               stepSizeTheta = 0; %do not make any changes to the theta in the next iteration
                               nAdjustments = nAdjustments + 1;
                               continue %start the next iteration of the while loop (skip the rest of the code in the loop)
                           elseif dist < distanceThres - distanceThresTolerance_c && zCurrent - 0.05 <= zMin &&  hoppedSide == false
                               %zCurrent = zCurrent - 0.05; %move down a little on the object
                               if thetaCurrent == 0
                                   thetaCurrent = thetaLimit; %do not make any changes to the theta in the next iteration
                               else
                                   thetaCurrent = -thetaCurrent; %do not make any changes to the theta in the next iteration
                               end
                               hoppedSide = true;
                               stepSizeTheta = 0; %do not make any changes to the theta in the next iteration
                               nAdjustments = nAdjustments + 1;
                               continue %start the next iteration of the while loop (skip the rest of the code in the loop)

                           %if the distance between the points are over the
                           %accepted maximum distance, and we have not
                           %passed the z hight of the row above; move up on
                           %the object to shorten the distance:
                           elseif dist > distanceThres + distanceThresTolerance_c && zCurrent + 0.01 < mean(aboveRowPoints(:,3))
                               zCurrent = zCurrent + 0.01; %move up a little on the object (not same amount as when moving done, to catch areas inbetween)
                               stepSizeTheta = 0; %do not make any changes to theta in the next iteration
                               nAdjustments = nAdjustments + 1;
                               continue
                           end
                           %if these above statesments are false, the
                           %point's distance to closest point above is
                           %acceptable, and the algorithm can proceed to
                           %save the point position.

                           %Note: when a adjustment is done in the Z value,
                           %this can affect the distance between points on
                           %the same row, therefore the algorithm will next
                           %adapt the distance between points on the same
                           %row to be correct. This may result in the Z 
                           % value having to be adjusted again. This can 
                           % become a long iterative process, hence the
                           % the adjustment limit is needed to increase
                           % tolerance and cut computation time. 
                      
                       end
                       if i > 1 && j == 104
                           inCell = any(cellfun(@(x) isequal(x, newPointCart), pointsCell(:, j)));
                           %break
                       end

                       %Save the accepted point position
                       pointsCell(i, j) = {newPointCart};
                       pointCellPol(i,j) = {newPoint};
                       i = i+1;
                       %If you want to visually see the points on the
                       %object (for debugging etc), uncomment this line:
                       %plot3(app.UIAxes, newPointCart(1), newPointCart(2), newPointCart(3),'.','MarkerSize',10, 'Color','cyan');

                       %reset the stepsize, nr of adjustments and the
                       %distance tolerance for the next point:
                       stepSizeTheta = originalStepSizeTheta;
                       nAdjustments = 0; 
                       distanceThresTolerance_c = distanceThresTolerance;

                       %If this was the last point on the row, mark it as
                       %complete:
                       if lastPointInRow
                           lastPointInRowDone = true;
                       end
                   else
                       %If the distance on the row was not accepted because
                       % it's larger than maximum tolerated distance, decrease
                       % theta in the next iteration (go back a bit):
                       if distance > distanceThres + distanceThresTolerance_c
                           stepSizeTheta = -0.005;

                       %If the distance on the row was not accepted because the
                       %point is too close to the first point in the row (we
                       %have almost completed a round) mark it as the last
                       %point and go back a little:
                       elseif distance < distanceThres + distanceThresTolerance_c && sideSwitched && k == 1 && height(sameRowPoints) > 1
                           lastPointInRow = true;
                           stepSizeTheta = -0.005;
                       %Else if the distance was to large between the points,
                       %increase the theta in the next iteration:
                       else
                           stepSizeTheta = originalStepSizeTheta;
                           if sideSwitched && k == 1
                               lastPointInRow = true;
                           end
                       end
                       prevPoint = cell2mat(pointCellPol(i-1,j));
                       prevPointTheta = prevPoint(1);
                       prevPointZ = prevPoint(3);

                       if thetaCurrent + stepSizeTheta <= prevPointTheta && thetaCurrent > 0 && prevPointTheta > 0 ||  thetaCurrent + stepSizeTheta <= prevPointTheta && thetaCurrent < 0 && prevPointTheta < 0%We do not want to go back over the previous points -> problem must exist in z instead
                           stepSizeTheta = 0;
                           if prevPointZ > newPoint(3) %If previous point is higher than the new point, minimize distance by moving new point up
                               zCurrent = zCurrent + 0.01;
                           else %If previous point is lower than the new point, minimize distance by moving new point down
                               zCurrent = zCurrent - 0.01;
                           end

                       end
                       nAdjustments = nAdjustments + 1;

                   end

               end
               zCurrent = zCurrent - stepSizeZ; %row is done, go down a bit to begin the next row
               stepSizeTheta = originalStepSizeTheta; %reset the stepsize
               test = cell2mat(pointsCell(:, j));
               pointList = vertcat(pointList, cell2mat(pointsCell(:, j))); %Add the points in the completed row to the total list of points
               j = j+1;
           end
           %point generation is done


       end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


        end

        % Button pushed function: ScanmodelButton
        function ScanmodelButtonPushed(app, event)
            a = load("D:\GitHub\MicroTomographyHT2022\PointsForGUI_2.mat");
            rng(0,'twister')

            %[pos, ~] = ReceiveAndUnpackPose(app, app.connection);
            pos = [-200,0,-40]';
            [x1,y1,z1] = ellipsoid(0,0,0,50,50,90);
            x1 = x1(:);
            y1 = y1(:);
            z1 = z1(:);
            p = [x1 y1 z1];
            p = unique(p,'rows');
            n = length(p);
            j = 1;
            %p = transRLtoPlot(app, p);
            %YumiModelPos = transRLtoPlot(app, pos');
            
            %app.currentDeg = eulerDeg;
            for i = 1:n
                if p(i,3) <= 0
                    Pn(j,:) = p(i, :);
                    j = j+1;

                end
            end
%             Pn = transRLtoPlot(app, Pn);

            a.meshEdgesMat = unique(a.meshEdgesMat,'rows');
            Pn = transRLtoPlot(app, a.meshEdgesMat);
            Pn = [Pn(:, 1) Pn(:,3), Pn(:,2)];
            app.objPoints = Pn;
            
            YumiModelPos = transRLtoPlot(app, pos');
            app.currentPose = YumiModelPos;


            hold(app.UIAxes,'on')
            %plot3(app.UIAxes, P(:,1),P(:,2),P(:,3),'.','MarkerSize',10);
            grid(app.UIAxes, 'on')
            %tetramesh(k, Pn, 'Parent', app.UIAxes);
            %plot3(app.UIAxes, Pn(:,1),Pn(:,2),Pn(:, 3),'.')
            shp = alphaShape(Pn(:,1),Pn(:,2),Pn(:,3),1);
            shp.Alpha = 200;
            %, 'FaceColor','green','FaceAlpha',0.6, 'EdgeColor','none'
            plot(shp, 'Parent', app.UIAxes, 'FaceAlpha',0.8, 'EdgeColor','none');
            app.wantedTCPplotPos = plot3(app.UIAxes, YumiModelPos(1), YumiModelPos(2), YumiModelPos(3),'.','MarkerSize',100, 'Color','Red');
            getFaceNormals(app, shp);
            %interpolate surface for later estimation on scanning points
            app.objPoints = [app.objPoints; app.centerPoints];
            %plot3(app.UIAxes, app.objPoints(:,1),app.objPoints(:,2),app.objPoints(:, 3),'.')
            [t, r, z] = cart2pol(app.objPoints(:,1), app.objPoints(:,2), app.objPoints(:,3));

            %add all rows where t = 3.1416 also as the inverted t, to help
            %interpolation at sign switch when switching side in
            %cylindrical coordinate system
            indexPi = find(t>=3.1);
            for i = 1:height(indexPi)
                t(end+1) = -t(indexPi(i));
                r(end+1) = r(indexPi(i));
                z(end+1) = z(indexPi(i));
            end

            app.surfaceInterpolation = scatteredInterpolant(t, z, r);
            updateTCPcoordAndAngle(app);
            app.shape = shp;
            %app.generatedPoints = generateScanningPoints(app, app.objPoints);
        end

        % Value changed function: CoordinateSystemDropDown
        function CoordinateSystemDropDownValueChanged(app, event)
            updateTCPcoordAndAngle(app);
            if app.CoordinateSystemDropDown.Value == "Carteasian"
                app.XLabel.Text = "X";
                app.YLabel.Text = "Y";
                app.ZLabel.Text = "Z";

                app.XLabel_2.Text = "X";
                app.YLabel_2.Text = "Y";
                app.ZLabel_2.Text = "Z";


            elseif app.CoordinateSystemDropDown.Value == "Cylindrical"
                app.XLabel.Text = "R";
                app.YLabel.Text = "θ";
                app.ZLabel.Text = "Z";

                app.XLabel_2.Text = "R";
                app.YLabel_2.Text = "θ";
                app.ZLabel_2.Text = "Z";

            elseif app.CoordinateSystemDropDown.Value == "Spherical"
                app.XLabel.Text = "Φ";
                app.YLabel.Text = "θ";
                app.ZLabel.Text = "ρ";

                app.XLabel_2.Text = "Φ";
                app.YLabel_2.Text = "θ";
                app.ZLabel_2.Text = "ρ";

            end


        end

        % Button pushed function: manualXRightBtn
        function manualXRightBtnPushed(app, event)

            if app.CoordinateSystemDropDown.Value == "Carteasian"
                newStep = app.wantedTCPplotPos.XData + app.StepsizeSpinner.Value;
                allowed = app.testIfStepAllowed([newStep, app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData]);
                if allowed
                    app.wantedTCPplotPos.XData = newStep;
                    updateTCPcoordAndAngle(app);
                end

            elseif app.CoordinateSystemDropDown.Value == "Cylindrical"
                [theta, r, z] = cart2pol(app.wantedTCPplotPos.XData,app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData);
                [newStepX, newStepY, newStepZ] = pol2cart(theta, r + app.StepsizeSpinner.Value, z);
                allowed = app.testIfStepAllowed([newStepX, newStepY, newStepZ]);
                if allowed
                    app.wantedTCPplotPos.XData = newStepX;
                    app.wantedTCPplotPos.YData = newStepY;
                    app.wantedTCPplotPos.ZData = newStepZ;
                    updateTCPcoordAndAngle(app);
                end
            elseif app.CoordinateSystemDropDown.Value == "Spherical"
                [theta, phi, rho] = cart2sph(app.wantedTCPplotPos.XData,app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData);
                [newStepX, newStepY, newStepZ] = sph2cart(theta, phi + app.StepsizeSpinner.Value, rho);
                allowed = app.testIfStepAllowed([newStepX, newStepY, newStepZ]);
                if allowed
                    app.wantedTCPplotPos.XData = newStepX;
                    app.wantedTCPplotPos.YData = newStepY;
                    app.wantedTCPplotPos.ZData = newStepZ;
                    updateTCPcoordAndAngle(app);
                end

            end


        end

        % Button pushed function: ManualXLeftBtn
        function ManualXLeftBtnButtonPushed(app, event)
            if app.CoordinateSystemDropDown.Value == "Carteasian"
                newStep = app.wantedTCPplotPos.XData - app.StepsizeSpinner.Value;
                allowed = app.testIfStepAllowed([newStep, app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData]);
                if allowed
                    app.wantedTCPplotPos.XData = newStep;
                    updateTCPcoordAndAngle(app);
                end

            elseif app.CoordinateSystemDropDown.Value == "Cylindrical"
                [theta, r, z] = cart2pol(app.wantedTCPplotPos.XData,app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData);
                [newStepX, newStepY, newStepZ] = pol2cart(theta, r - app.StepsizeSpinner.Value, z);
                allowed = app.testIfStepAllowed([newStepX, newStepY, newStepZ]);
                if allowed
                    app.wantedTCPplotPos.XData = newStepX;
                    app.wantedTCPplotPos.YData = newStepY;
                    app.wantedTCPplotPos.ZData = newStepZ;
                    updateTCPcoordAndAngle(app);
                end

            elseif app.CoordinateSystemDropDown.Value == "Spherical"
                [theta, phi, rho] = cart2sph(app.wantedTCPplotPos.XData,app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData);  
                [newStepX, newStepY, newStepZ] = sph2cart(theta, phi - app.StepsizeSpinner.Value, rho);
                allowed = app.testIfStepAllowed([newStepX, newStepY, newStepZ]);
                if allowed
                    app.wantedTCPplotPos.XData = newStepX;
                    app.wantedTCPplotPos.YData = newStepY;
                    app.wantedTCPplotPos.ZData = newStepZ;
                    updateTCPcoordAndAngle(app);
                end

            end
        end

        % Button pushed function: ManualZUpBtn
        function ManualZUpBtnButtonPushed(app, event)
            if app.CoordinateSystemDropDown.Value == "Carteasian"
                newStep = app.wantedTCPplotPos.ZData + app.StepsizeSpinner.Value;
                allowed = app.testIfStepAllowed([app.wantedTCPplotPos.XData, app.wantedTCPplotPos.YData, newStep]);
                if allowed
                    app.wantedTCPplotPos.ZData = newStep;
                    updateTCPcoordAndAngle(app);
                end

            elseif app.CoordinateSystemDropDown.Value == "Cylindrical"
                [theta, r, z] = cart2pol(app.wantedTCPplotPos.XData,app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData);
                [newStepX, newStepY, newStepZ] = pol2cart(theta, r, z + app.StepsizeSpinner.Value);
                allowed = app.testIfStepAllowed([newStepX, newStepY, newStepZ]);
                if allowed
                    app.wantedTCPplotPos.XData = newStepX;
                    app.wantedTCPplotPos.YData = newStepY;
                    app.wantedTCPplotPos.ZData = newStepZ;
                    updateTCPcoordAndAngle(app);
                end

            elseif app.CoordinateSystemDropDown.Value == "Spherical"
                [theta, phi, rho] = cart2sph(app.wantedTCPplotPos.XData,app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData);
                [newStepX, newStepY, newStepZ] = sph2cart(theta, phi, rho + app.StepsizeSpinner.Value);
                allowed = app.testIfStepAllowed([newStepX, newStepY, newStepZ]);
                if allowed
                    app.wantedTCPplotPos.XData = newStepX;
                    app.wantedTCPplotPos.YData = newStepY;
                    app.wantedTCPplotPos.ZData = newStepZ;
                    updateTCPcoordAndAngle(app);
                end

            end
        end

        % Button pushed function: ManualZDownBtn
        function ManualZDownBtnButtonPushed(app, event)
            if app.CoordinateSystemDropDown.Value == "Carteasian"
                newStep = app.wantedTCPplotPos.ZData - app.StepsizeSpinner.Value;
                allowed = app.testIfStepAllowed([app.wantedTCPplotPos.XData, app.wantedTCPplotPos.YData, newStep]);
                if allowed
                    app.wantedTCPplotPos.ZData = newStep;
                    updateTCPcoordAndAngle(app);
                end
            elseif app.CoordinateSystemDropDown.Value == "Cylindrical"
                [theta, r, z] = cart2pol(app.wantedTCPplotPos.XData,app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData);
                [newStepX, newStepY, newStepZ] = pol2cart(theta, r, z - app.StepsizeSpinner.Value);
                allowed = app.testIfStepAllowed([newStepX, newStepY, newStepZ]);
                if allowed
                    app.wantedTCPplotPos.XData = newStepX;
                    app.wantedTCPplotPos.YData = newStepY;
                    app.wantedTCPplotPos.ZData = newStepZ;
                    updateTCPcoordAndAngle(app);
                end

            elseif app.CoordinateSystemDropDown.Value == "Spherical"
                [theta, phi, rho] = cart2sph(app.wantedTCPplotPos.XData,app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData);
                [newStepX, newStepY, newStepZ] = sph2cart(theta, phi, rho - app.StepsizeSpinner.Value);
                allowed = app.testIfStepAllowed([newStepX, newStepY, newStepZ]);
                if allowed
                    app.wantedTCPplotPos.XData = newStepX;
                    app.wantedTCPplotPos.YData = newStepY;
                    app.wantedTCPplotPos.ZData = newStepZ;
                    updateTCPcoordAndAngle(app);
                end

            end
        end

        % Button pushed function: manualYRightBtn
        function manualYRightBtnButtonPushed(app, event)
            if app.CoordinateSystemDropDown.Value == "Carteasian"
                newStep = app.wantedTCPplotPos.YData + app.StepsizeSpinner.Value;
                allowed = app.testIfStepAllowed([app.wantedTCPplotPos.XData, newStep, app.wantedTCPplotPos.ZData]);
                if allowed
                    app.wantedTCPplotPos.YData = newStep;
                    updateTCPcoordAndAngle(app);
                end

            elseif app.CoordinateSystemDropDown.Value == "Cylindrical"
                [theta, r, z] = cart2pol(app.wantedTCPplotPos.XData,app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData);
                [newStepX, newStepY, newStepZ] = pol2cart(theta + app.StepsizeSpinner.Value, r, z);
                allowed = app.testIfStepAllowed([newStepX, newStepY, newStepZ]);
                if allowed
                    app.wantedTCPplotPos.XData = newStepX;
                    app.wantedTCPplotPos.YData = newStepY;
                    app.wantedTCPplotPos.ZData = newStepZ;
                    updateTCPcoordAndAngle(app);
                end

            elseif app.CoordinateSystemDropDown.Value == "Spherical"
                [theta, phi, rho] = cart2sph(app.wantedTCPplotPos.XData,app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData);  
                [newStepX, newStepY, newStepZ] = sph2cart(theta + app.StepsizeSpinner.Value, phi, rho);
                allowed = app.testIfStepAllowed([newStepX, newStepY, newStepZ]);
                if allowed
                    app.wantedTCPplotPos.XData = newStepX;
                    app.wantedTCPplotPos.YData = newStepY;
                    app.wantedTCPplotPos.ZData = newStepZ;
                    updateTCPcoordAndAngle(app);
                end

            end
        end

        % Button pushed function: ManualYLeftBtn
        function ManualYLeftBtnButtonPushed(app, event)
             if app.CoordinateSystemDropDown.Value == "Carteasian"
                newStep = app.wantedTCPplotPos.YData - app.StepsizeSpinner.Value;
                allowed = app.testIfStepAllowed([app.wantedTCPplotPos.XData, newStep, app.wantedTCPplotPos.ZData]);
                if allowed
                    app.wantedTCPplotPos.YData = newStep;
                    updateTCPcoordAndAngle(app);
                end
             elseif app.CoordinateSystemDropDown.Value == "Cylindrical"
                [theta, r, z] = cart2pol(app.wantedTCPplotPos.XData,app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData);
                [newStepX, newStepY, newStepZ] = pol2cart(theta - app.StepsizeSpinner.Value, r, z);
                allowed = app.testIfStepAllowed([newStepX, newStepY, newStepZ]);
                if allowed
                    app.wantedTCPplotPos.XData = newStepX;
                    app.wantedTCPplotPos.YData = newStepY;
                    app.wantedTCPplotPos.ZData = newStepZ;
                    updateTCPcoordAndAngle(app);
                end

             elseif app.CoordinateSystemDropDown.Value == "Spherical"
                [theta, phi, rho] = cart2sph(app.wantedTCPplotPos.XData,app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData);  
                [newStepX, newStepY, newStepZ] = sph2cart(theta - app.StepsizeSpinner.Value, phi, rho);
                allowed = app.testIfStepAllowed([newStepX, newStepY, newStepZ]);
                if allowed
                    app.wantedTCPplotPos.XData = newStepX;
                    app.wantedTCPplotPos.YData = newStepY;
                    app.wantedTCPplotPos.ZData = newStepZ;
                    updateTCPcoordAndAngle(app);
                end

            end
        end

        % Value changed function: StepsizeSpinner
        function StepsizeSpinnerValueChanged(app, event)

            
        end

        % Button pushed function: DriveawayfromobjectButton
        function DriveawayfromobjectButtonPushed(app, event)
            if app.wantedTCPplotPos.XData > 0
                app.wantedTCPplotPos.XData = app.wantedTCPplotPos.XData + 50;
            else
                app.wantedTCPplotPos.XData = app.wantedTCPplotPos.XData - 50;
            end

            if app.wantedTCPplotPos.YData > 0
                app.wantedTCPplotPos.YData = app.wantedTCPplotPos.YData + 50;
            else
                app.wantedTCPplotPos.YData = app.wantedTCPplotPos.YData - 50;
            end

            updateTCPcoordAndAngle(app);
        end

        % Button pushed function: connecttorobotButton
        function connecttorobotButtonPushed(app, event)
               connected = connectToYumi(app);
               %if connected
                %   app.f = parfeval(backgroundPool,@getAndPlotTCPpos,0,1);

               %end
        end

        % Callback function
        function SelectpointsButtonPushed(app, event)
            d = datacursormode(app.UIFigure);
            d.Enable = 'on';
            d.SnapToDataVertex = 'off';
            d.UpdateFcn = @(hObj, event, ax)updatePoints(app, hObj, event, app.UIAxes, d);
        end

        % Button pushed function: ClearallpointsButton
        function ClearallpointsButtonPushed(app, event)
            for i=1:numel(app.selectedPoints)
                delete(app.selectedPoints(i));
            end
            delete(app.areaBoundary);
            app.selectedPoints = [];
            set(app.UITable, 'Data', []);
        end

        % Value changed function: SelectpointsButton
        function SelectpointsButtonValueChanged(app, event)
            selectingPoints = app.SelectpointsButton.Value;
            if selectingPoints
                app.dataCursor = datacursormode(app.UIFigure);
                app.dataCursor.removeAllDataCursors();
                app.dataCursor.Enable = 'on';
                app.dataCursor.SnapToDataVertex = 'off';
                app.dataCursor.UpdateFcn = @(hObj, event_)updatePoints(app, hObj, event_ , app.UIAxes);
            else
                app.dataCursor.removeAllDataCursors();
                app.dataCursor.Enable = 'off';
                app.dataCursor.UpdateFcn = @(hObj, event_)makeDataTipInvisible(app, hObj, event_);
            end
        end

        % Selection changed function: ScanmodeButtonGroup
        function ScanmodeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ScanmodeButtonGroup.SelectedObject;
            if selectedButton == app.ScanwholeobjectButton
                app.PointSelectionDropDown.Enable = "on";
                app.PointSelectionValueSpinner.Enable = "on";

                app.UITable.Enable = "off";
                app.SelectpointsButton.Enable = "off";
                app.ClearallpointsButton.Enable = "off";
                app.ScanningpointselectionPanel.Enable = "off";
                app.ScanningpointgenerationPanel.Enable = "on";

                if ~isempty(app.dataCursor)
                    app.dataCursor.removeAllDataCursors();
                    app.dataCursor.Enable = 'off';
                    app.dataCursor.UpdateFcn = @(hObj, event_)makeDataTipInvisible(app, hObj, event_);

                    for i=1:numel(app.selectedPoints)
                        delete(app.selectedPoints(i));
                    end
                    app.selectedPoints = [];
                    set(app.UITable, 'Data', []);
                    app.SelectpointsButton.Value = 0;
                end


            elseif selectedButton == app.ScanareaButton

                app.UITable.Enable = "on";
                app.SelectpointsButton.Enable = "on";
                app.ClearallpointsButton.Enable = "on";
                app.ScanningpointselectionPanel.Enable = "on";
                app.ScanningpointgenerationPanel.Enable = "on";
                app.ScanningpointselectionPanel.Title = "Boundery point selection";
            elseif selectedButton == app.ScanselectionofpointsButton
                app.PointSelectionDropDown.Enable = "off";
                app.PointSelectionValueSpinner.Enable = "off";

                app.UITable.Enable = "on";
                app.SelectpointsButton.Enable = "on";
                app.ClearallpointsButton.Enable = "on";
                app.ScanningpointselectionPanel.Enable = "on";
                app.ScanningpointgenerationPanel.Enable = "off";
                app.ScanningpointselectionPanel.Title = "Scanning point selection";
            end

        end

        % Button pushed function: previewscanningpointsButton
        function previewscanningpointsButtonPushed(app, event)
            %[~, xyz] = boundaryFacets(app.shape);
            %x = 12.7;
            %y = 10;
            %z = 21.4;
            %tTest = -1.44;
            %zTest = -20;
            %xyz = [xyz; app.centerPoints];
            %[t, r, z] = cart2pol(xyz(:,1), xyz(:,2), xyz(:,3));
            %finter = scatteredInterpolant(t, z, r);
            %newPointR = finter(tTest, zTest);
            %[nx, ny,nz] = pol2cart(tTest, newPointR, zTest);
            %plot3(app.UIAxes, nx, ny, nz,'.','MarkerSize',10, 'Color','cyan');
            %or = app.existingPointPos;
            if app.ScanmodeButtonGroup.SelectedObject == app.ScanareaButton
                sortedX = sortrows(app.existingPointPos);
                sortedZ = sortrows(app.existingPointPos, 3);
                bottomPos = sortedZ(1:2, 3);
                topPos = sortedZ(3:4, 3);
                if ismember(sortedX(1,3), topPos) && ismember(sortedX(2,3), bottomPos) || ismember(sortedX(1,3), bottomPos) && ismember(sortedX(2,3), topPos)
                    if sortedX(1,3) > sortedX(2,3)
                        tLp = sortedX(1, :);
                        bLp = sortedX(2, :);
                    else
                        tLp = sortedX(2, :);
                        bLp = sortedX(1, :);
                    end
                    if sortedX(3,3) > sortedX(4,3)
                        tRp = sortedX(3, :);
                        bRp = sortedX(4, :);
                    else
                        tRp = sortedX(4, :);
                        bRp = sortedX(3, :);
                    end
                else
                    sortedY = sortrows(app.existingPointPos, 2);
                    if sortedY(1,3) > sortedY(2,3)
                        tLp = sortedY(1, :);
                        bLp = sortedY(2, :);
                    else
                        tLp = sortedY(2, :);
                        bLp = sortedY(1, :);
                    end
                    if sortedY(3,3) > sortedY(4,3)
                        tRp = sortedY(3, :);
                        bRp = sortedY(4, :);
                    else
                        tRp = sortedY(4, :);
                        bRp = sortedY(3, :);
                    end

                end
            else
                pointDensity = app.PointdensitySlider.Value/100;
                nGeneratedpoints = height(app.generatedPoints);
                nrOfPoints = nGeneratedpoints*pointDensity;
                stepSize = nGeneratedpoints/nrOfPoints;
                scanningPoints = app.generatedPoints(1:stepSize:end, :);
                plot3(app.UIAxes, scanningPoints(:,1), scanningPoints(:,2), scanningPoints(:,3),'.','MarkerSize',10, 'Color','cyan');


            end
            

%             topLineX = linspace(tLp(1), tRp(1), 5);
%             topLineY = linspace(tLp(2), tRp(2), 5);
%             topLineZ = linspace(tLp(3), tRp(3), 5);
%             plot3(app.UIAxes, topLineX, topLineY, topLineZ,'.','MarkerSize',10, 'Color','cyan');
%             bottomLineX = linspace(bLp(1), bRp(1), 5);
%             bottomLineY = linspace(bLp(2), bRp(2), 5);
%             bottomLineZ = linspace(bLp(3), bRp(3), 5);
%             plot3(app.UIAxes, bottomLineX, bottomLineY, bottomLineZ,'.','MarkerSize',10, 'Color','cyan');


%             for i = 1:numel(app.selectedPoints)
%                 for j = 2:numel(app.selectedPoints)
%                     p1 = app.existingPointPos(i, :);
%                     p2 = app.existingPointPos(j, :);
%                     linesX = linspace(app.existingPointPos(i, 1), app.existingPointPos(j, 1), 5)';
%                     linesY = linspace(app.existingPointPos(i, 2), app.existingPointPos(j, 2), 5)';
%                     linesZ = linspace(app.existingPointPos(i, 3), app.existingPointPos(j, 3), 5)';
%                     plot3(app.UIAxes, linesX, linesY, linesZ,'.','MarkerSize',10, 'Color','cyan');
%                 end
%                 
%             end


        end

        % Value changed function: PointdensitySlider
        function PointdensitySliderValueChanged(app, event)
            value = app.PointdensitySlider.Value;
            pointDistance = round(52-(value/2));
            pointList = generateScanningPoints(app, app.shape, pointDistance);
            %[plottedPointsPol(:,1), plottedPointsPol(:,2), plottedPointsPol(:,3)] = cart2pol(pointList(:,1),pointList(:,2) , pointList(:,3));
            %plottedPointsPol(:,2) = plottedPointsPol(:,2) + 1;
            %[plottedPointsPol(:,1), plottedPointsPol(:,2), plottedPointsPol(:,3)] = pol2cart(plottedPointsPol(:,1), plottedPointsPol(:,2), plottedPointsPol(:,3));

            if ~isempty(app.scanningPointPreviewPlot)
                delete(app.scanningPointPreviewPlot);
            end
            %app.scanningPointPreviewPlot = plot3(app.UIAxes, plottedPointsPol(:, 1), plottedPointsPol(:, 2), plottedPointsPol(:, 3),'.','MarkerSize',10, 'Color','cyan');
            app.scanningPointPreviewPlot = plot3(app.UIAxes, pointList(:, 1), pointList(:, 2), pointList(:, 3),'.','MarkerSize',10, 'Color','cyan');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Object reconstruction')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.AutomaticControlPanel = uipanel(app.UIFigure);
            app.AutomaticControlPanel.Title = 'Automatic Control';
            app.DmodelreconstructionPanel = uipanel(app.AutomaticControlPanel);
            app.DmodelreconstructionPanel.Title = '3D-model reconstruction';
            app.ScanmodelButton = uibutton(app.DmodelreconstructionPanel, 'push');
            app.ScanmodelButton.ButtonPushedFcn = createCallbackFcn(app, @ScanmodelButtonPushed, true);
            app.ScanmodelButton.Text = 'Scan model';
            app.ScanningprotocolDropDownLabel = uilabel(app.DmodelreconstructionPanel);
            app.ScanningprotocolDropDownLabel.Text = 'Scanning protocol';
            app.ScanningprotocolDropDown = uidropdown(app.DmodelreconstructionPanel);
            app.DatahandelingButtonGroup = uibuttongroup(app.DmodelreconstructionPanel);
            app.DatahandelingButtonGroup.Title = 'Data handeling';
            app.ReplacedataButton = uitogglebutton(app.DatahandelingButtonGroup);
            app.ReplacedataButton.Text = 'Replace data';
            app.ReplacedataButton.Value = true;
            app.ComplimentdataButton = uitogglebutton(app.DatahandelingButtonGroup);
            app.ComplimentdataButton.Text = 'Compliment data';
            app.connecttorobotButton = uibutton(app.DmodelreconstructionPanel, 'push');
            app.connecttorobotButton.ButtonPushedFcn = createCallbackFcn(app, @connecttorobotButtonPushed, true);
            app.connecttorobotButton.Text = 'connect to robot';
            app.MicrowavescanningsettingsPanel = uipanel(app.AutomaticControlPanel);
            app.MicrowavescanningsettingsPanel.Title = 'Microwave scanning settings';
            app.ScanmodeButtonGroup = uibuttongroup(app.MicrowavescanningsettingsPanel);
            app.ScanmodeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ScanmodeButtonGroupSelectionChanged, true);
            app.ScanmodeButtonGroup.Title = 'Scan mode';
            app.ScanwholeobjectButton = uitogglebutton(app.ScanmodeButtonGroup);
            app.ScanwholeobjectButton.Text = 'Scan whole object';
            app.ScanwholeobjectButton.Value = true;
            app.ScanareaButton = uitogglebutton(app.ScanmodeButtonGroup);
            app.ScanareaButton.Text = 'Scan area';
            app.ScanselectionofpointsButton = uitogglebutton(app.ScanmodeButtonGroup);
            app.ScanselectionofpointsButton.Text = 'Scan selection of points';
            app.DistancetoobjectSpinnerLabel = uilabel(app.ScanmodeButtonGroup);
            app.DistancetoobjectSpinnerLabel.Text = 'Distance to object';
            app.DistancetoobjectSpinner = uispinner(app.ScanmodeButtonGroup);
            app.StartmicroscanButton = uibutton(app.MicrowavescanningsettingsPanel, 'push');
            app.StartmicroscanButton.Text = 'Start micro scan';
            app.VNAPanel = uipanel(app.MicrowavescanningsettingsPanel);
            app.VNAPanel.Title = 'VNA';
            app.ScanningpointgenerationPanel = uipanel(app.MicrowavescanningsettingsPanel);
            app.ScanningpointgenerationPanel.Title = 'Scanning point generation';
            app.PointdensitySliderLabel = uilabel(app.ScanningpointgenerationPanel);
            app.PointdensitySliderLabel.Text = 'Point density';
            app.PointdensitySlider = uislider(app.ScanningpointgenerationPanel);
            app.PointdensitySlider.ValueChangedFcn = createCallbackFcn(app, @PointdensitySliderValueChanged, true);
            app.PointdensitySlider.Value = 50;
            app.nPointsValueLabel = uilabel(app.ScanningpointgenerationPanel);
            app.nPointsValueLabel.Text = '50';
            app.pointsLabel = uilabel(app.ScanningpointgenerationPanel);
            app.pointsLabel.Text = 'points';
            app.ScanningpointselectionPanel = uipanel(app.MicrowavescanningsettingsPanel);
            app.ScanningpointselectionPanel.Enable = 'off';
            app.ScanningpointselectionPanel.Title = 'Scanning point selection';
            app.UITable = uitable(app.ScanningpointselectionPanel);
            app.UITable.ColumnName = {'Point id'; 'X'; 'Y'; 'Z'};
            app.UITable.RowName = {};
            app.UITable.Enable = 'off';
            app.SelectpointsButton = uibutton(app.ScanningpointselectionPanel, 'state');
            app.SelectpointsButton.ValueChangedFcn = createCallbackFcn(app, @SelectpointsButtonValueChanged, true);
            app.SelectpointsButton.Enable = 'off';
            app.SelectpointsButton.Text = 'Select points';
            app.ClearallpointsButton = uibutton(app.ScanningpointselectionPanel, 'push');
            app.ClearallpointsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearallpointsButtonPushed, true);
            app.ClearallpointsButton.Enable = 'off';
            app.ClearallpointsButton.Text = 'Clear all points';
            app.previewscanningpointsButton = uibutton(app.MicrowavescanningsettingsPanel, 'push');
            app.previewscanningpointsButton.ButtonPushedFcn = createCallbackFcn(app, @previewscanningpointsButtonPushed, true);
            app.previewscanningpointsButton.Text = 'preview scanning points';
            app.ManualControlPanel = uipanel(app.UIFigure);
            app.ManualControlPanel.Title = 'Manual Control';
            app.ManualZUpBtn = uibutton(app.ManualControlPanel, 'push');
            app.ManualZUpBtn.ButtonPushedFcn = createCallbackFcn(app, @ManualZUpBtnButtonPushed, true);
            app.ManualZUpBtn.Icon = fullfile(pathToMLAPP, 'icons', 'plus.png');
            app.ManualZUpBtn.Text = '';
            app.ManualXLeftBtn = uibutton(app.ManualControlPanel, 'push');
            app.ManualXLeftBtn.ButtonPushedFcn = createCallbackFcn(app, @ManualXLeftBtnButtonPushed, true);
            app.ManualXLeftBtn.Icon = fullfile(pathToMLAPP, 'icons', 'minus.png');
            app.ManualXLeftBtn.Text = '';
            app.manualXRightBtn = uibutton(app.ManualControlPanel, 'push');
            app.manualXRightBtn.ButtonPushedFcn = createCallbackFcn(app, @manualXRightBtnPushed, true);
            app.manualXRightBtn.Icon = fullfile(pathToMLAPP, 'icons', 'plus.png');
            app.manualXRightBtn.Text = '';
            app.ManualZDownBtn = uibutton(app.ManualControlPanel, 'push');
            app.ManualZDownBtn.ButtonPushedFcn = createCallbackFcn(app, @ManualZDownBtnButtonPushed, true);
            app.ManualZDownBtn.Icon = fullfile(pathToMLAPP, 'icons', 'minus.png');
            app.ManualZDownBtn.Text = '';
            app.CoordinateSystemDropDownLabel = uilabel(app.ManualControlPanel);
            app.CoordinateSystemDropDownLabel.Text = 'Coordinate System';
            app.CoordinateSystemDropDown = uidropdown(app.ManualControlPanel);
            app.CoordinateSystemDropDown.Items = {'Carteasian', 'Cylindrical', 'Spherical'};
            app.CoordinateSystemDropDown.ValueChangedFcn = createCallbackFcn(app, @CoordinateSystemDropDownValueChanged, true);
            app.CoordinateSystemDropDown.Value = 'Carteasian';
            app.MinimumdistancetoobjectSpinnerLabel = uilabel(app.ManualControlPanel);
            app.MinimumdistancetoobjectSpinnerLabel.Text = 'Minimum distance to object';
            app.MinimumdistancetoobjectSpinner = uispinner(app.ManualControlPanel);
            app.MinimumdistancetoobjectSpinner.Step = 0.5;
            app.MinimumdistancetoobjectSpinner.Limits = [0 10000];
            app.manualYRightBtn = uibutton(app.ManualControlPanel, 'push');
            app.manualYRightBtn.ButtonPushedFcn = createCallbackFcn(app, @manualYRightBtnButtonPushed, true);
            app.manualYRightBtn.Icon = fullfile(pathToMLAPP, 'icons', 'plus.png');
            app.manualYRightBtn.Text = '';
            app.ManualYLeftBtn = uibutton(app.ManualControlPanel, 'push');
            app.ManualYLeftBtn.ButtonPushedFcn = createCallbackFcn(app, @ManualYLeftBtnButtonPushed, true);
            app.ManualYLeftBtn.Icon = fullfile(pathToMLAPP, 'icons', 'minus.png');
            app.ManualYLeftBtn.Text = '';
            app.XLabel = uilabel(app.ManualControlPanel);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.ManualControlPanel);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.ManualControlPanel);
            app.ZLabel.Text = 'Z';
            app.StepsizeSpinnerLabel = uilabel(app.ManualControlPanel);
            app.StepsizeSpinnerLabel.Text = 'Step size';
            app.StepsizeSpinner = uispinner(app.ManualControlPanel);
            app.StepsizeSpinner.Step = 0.1;
            app.StepsizeSpinner.Limits = [0 100];
            app.StepsizeSpinner.ValueChangedFcn = createCallbackFcn(app, @StepsizeSpinnerValueChanged, true);
            app.StepsizeSpinner.Value = 0.1;
            app.XLabel_2 = uilabel(app.ManualControlPanel);
            app.XLabel_2.Text = 'X';
            app.YLabel_2 = uilabel(app.ManualControlPanel);
            app.YLabel_2.Text = 'Y';
            app.Label = uilabel(app.ManualControlPanel);
            app.Label.Text = '=';
            app.Label_2 = uilabel(app.ManualControlPanel);
            app.Label_2.Text = '=';
            app.tcpCordX = uilabel(app.ManualControlPanel);
            app.tcpCordX.Text = '0';
            app.tcpCordY = uilabel(app.ManualControlPanel);
            app.tcpCordY.Text = '0';
            app.TCPcoordinatesLabel = uilabel(app.ManualControlPanel);
            app.TCPcoordinatesLabel.Text = 'TCP coordinates:';
            app.Panel = uipanel(app.ManualControlPanel);
            app.ZLabel_2 = uilabel(app.Panel);
            app.ZLabel_2.Text = 'Z';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '=';
            app.tcpCordZ = uilabel(app.Panel);
            app.tcpCordZ.Text = '0';
            app.LogsTextAreaLabel = uilabel(app.UIFigure);
            app.LogsTextAreaLabel.Text = 'Logs';
            app.LogsTextArea = uitextarea(app.UIFigure);
            app.LogsTextArea.Value = {' '};
            app.EmergencystopButton = uibutton(app.UIFigure, 'push');
            app.EmergencystopButton.Text = 'Emergency stop';
            app.DriveawayfromobjectButton = uibutton(app.UIFigure, 'push');
            app.DriveawayfromobjectButton.ButtonPushedFcn = createCallbackFcn(app, @DriveawayfromobjectButtonPushed, true);
            app.DriveawayfromobjectButton.Text = 'Drive away from object';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TestLayout

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end