classdef AppMainLayout < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LogsTextArea                    matlab.ui.control.TextArea
        LogsTextAreaLabel               matlab.ui.control.Label
        ManualControlPanel              matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        Panel_2                         matlab.ui.container.Panel
        TCPcoordinatesLabel             matlab.ui.control.Label
        StepsizeSpinner                 matlab.ui.control.Spinner
        StepsizeSpinnerLabel            matlab.ui.control.Label
        MinimumdistancetoobjectSpinner  matlab.ui.control.Spinner
        MinimumdistancetoobjectSpinnerLabel  matlab.ui.control.Label
        CoordinateSystemDropDown        matlab.ui.control.DropDown
        CoordinateSystemDropDownLabel   matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        tcpCordZ                        matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        ZLabel_2                        matlab.ui.control.Label
        tcpCordY                        matlab.ui.control.Label
        tcpCordX                        matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        YLabel_2                        matlab.ui.control.Label
        XLabel_2                        matlab.ui.control.Label
        ZLabel                          matlab.ui.control.Label
        YLabel                          matlab.ui.control.Label
        XLabel                          matlab.ui.control.Label
        ManualYLeftBtn                  matlab.ui.control.Button
        manualYRightBtn                 matlab.ui.control.Button
        ManualZDownBtn                  matlab.ui.control.Button
        manualXRightBtn                 matlab.ui.control.Button
        ManualXLeftBtn                  matlab.ui.control.Button
        ManualZUpBtn                    matlab.ui.control.Button
        SenderrobotarmPanel             matlab.ui.container.Panel
        Button_2                        matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        laserscanthispointButton        matlab.ui.control.Button
        microscanthispointButton        matlab.ui.control.Button
        AutomaticControlPanel           matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        EditField_3                     matlab.ui.control.EditField
        MicrowavescanningsettingsPanel  matlab.ui.container.Panel
        ScanningpointselectionPanel     matlab.ui.container.Panel
        ClearallpointsButton            matlab.ui.control.Button
        SelectpointsButton              matlab.ui.control.StateButton
        UITable                         matlab.ui.control.Table
        ScanningpointgenerationPanel    matlab.ui.container.Panel
        Label_4                         matlab.ui.control.Label
        scanningpointsLabel             matlab.ui.control.Label
        nPointsValueLabel               matlab.ui.control.Label
        PointdensitySlider              matlab.ui.control.Slider
        PointdensitySliderLabel         matlab.ui.control.Label
        VNAPanel                        matlab.ui.container.Panel
        StartmicroscanButton            matlab.ui.control.Button
        ScanmodeButtonGroup             matlab.ui.container.ButtonGroup
        EditField_2                     matlab.ui.control.EditField
        EditField                       matlab.ui.control.EditField
        DistancetoobjectSpinner         matlab.ui.control.Spinner
        DistancetoobjectSpinnerLabel    matlab.ui.control.Label
        ScanselectionofpointsButton     matlab.ui.control.ToggleButton
        ScanareaButton                  matlab.ui.control.ToggleButton
        ScanwholeobjectButton           matlab.ui.control.ToggleButton
        DmodelreconstructionPanel       matlab.ui.container.Panel
        EditField_6                     matlab.ui.control.EditField
        EditField_5                     matlab.ui.control.EditField
        EditField_4                     matlab.ui.control.EditField
        connecttorobotButton            matlab.ui.control.Button
        ScanningprotocolDropDown        matlab.ui.control.DropDown
        ScanningprotocolDropDownLabel   matlab.ui.control.Label
        ScanmodelButton                 matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)

        objPoints;
        shape;
        wantedTCPplotPos;
        TCPplotPos;
        diffRLPlot;
        testingCoord;
        connection;
        currentPose;
        currentDeg;
        waitingOnPos;
        f;
        normals;
        centerPoints;
        dirArrow;
        scanDirArrow;
        dataCursor;
        areaBoundary;
        surfaceInterpolation;
        existingPointPos;
        selectedPoints;
        generatedPoints;
        scanningPointPreviewPlot;
        testPlot;
        prevDistanceValue;
        microScanningPoints; 
    end


    methods (Access = private)
        function [angle_H, angle_P, angle_B] = updateTCPcoordAndAngleDisp(app)
            %Updates the display of the TCP angle and position. 
            if ~isempty(app.wantedTCPplotPos)
                tcpPos = [app.wantedTCPplotPos.XData, app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData];
                [angle_H, angle_P, angle_B, k] = calcAngles(app, tcpPos, app.centerPoints, app.normals);
                %plot normal, origin from tcp point
                if isempty(app.dirArrow) %if not plotted before, plot, otherwise update coordinates
                    app.dirArrow = quiver3(app.wantedTCPplotPos.XData, app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData, app.normals(k,1), app.normals(k,2), app.normals(k,3), 'Color' , 'red', 'Parent', app.UIAxes, 'AutoScale','on', 'AutoScaleFactor',5, 'LineWidth',1.5, 'MaxHeadSize',20);
                else
                    app.dirArrow.XData = app.wantedTCPplotPos.XData;
                    app.dirArrow.YData = app.wantedTCPplotPos.YData;
                    app.dirArrow.ZData = app.wantedTCPplotPos.ZData;

                    app.dirArrow.UData = app.normals(k,1);
                    app.dirArrow.VData = app.normals(k,2);
                    app.dirArrow.WData = app.normals(k,3);
                end

                if app.CoordinateSystemDropDown.Value == "Carteasian"
                    app.tcpCordX.Text = string(app.wantedTCPplotPos.XData);
                    app.tcpCordY.Text = string(app.wantedTCPplotPos.YData);
                    app.tcpCordZ.Text = string(app.wantedTCPplotPos.ZData);

                elseif app.CoordinateSystemDropDown.Value == "Cylindrical"
                    [theta, r, z] = cart2pol(app.wantedTCPplotPos.XData,app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData);
                    app.tcpCordX.Text = string(r);
                    app.tcpCordY.Text = string(theta);
                    app.tcpCordZ.Text = string(z);

                elseif app.CoordinateSystemDropDown.Value == "Spherical"
                    [theta, phi, rho] = cart2sph(app.wantedTCPplotPos.XData,app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData);
                    app.tcpCordX.Text = string(phi);
                    app.tcpCordY.Text = string(theta);
                    app.tcpCordZ.Text = string(rho);
                end

            end
        end

        function updateTCPcoordAndAngleManual(app)
            %Sends pose to robot if the connection is open and updates
            %all displays of tcp
            [angle_H, angle_P, angle_B] = updateTCPcoordAndAngleDisp(app);

            if checkConnectionStatus(app, app.connection)
                newRealPose = [app.wantedTCPplotPos.XData, app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData];
                scanningPointTcpRotations = zeros(height(newRealPose), 3);
                scanningPointTcpRotations(:, :) = [0, 90-angle_P, -angle_H];
                packAndSendPose(newRealPose, scanningPointTcpRotations, 0, 100, 0, app.connection);
                pos = ReceiveAndUnpackPose(app.connection);
            end


        end


        function txt = updateClickedPoints(app, obj, event_obj, ax)
            %This function handles the interactive point selection, where the user
            %clicks on the object to select boundery or scanning points. The
            %function gets the clicked position, and check if it already exists
            %among the clicked points. If it does, remove the point, else add it.
            pos = get(event_obj, 'Position');
            doesNotExist = true;
            oldTableData = get(app.UITable,'Data');
            if app.ScanmodeButtonGroup.SelectedObject == app.ScanselectionofpointsButton
                app.existingPointPos = app.microScanningPoints;
            end
            %check with each previously clicked points if the newly clicked
            %point already exists. If it does, remove it from the list.
            for i=1:height(app.existingPointPos)
                n = app.existingPointPos(i,:);
                if isequal(pos, n)
                    doesNotExist = false;
                    app.existingPointPos(i, :) = [];
                    oldTableData(i, :) = [];
                    newTableData = oldTableData;
                    break
                end

            end

            if doesNotExist
                newTableData = [oldTableData; [height(oldTableData)+1 pos]];
                app.existingPointPos = [app.existingPointPos ; pos];

            end
            %updates the table that displays the points
            set(app.UITable, 'Data', newTableData);

            %if selected mode was "scan area", and 3 or more points have
            %been clicked, initiate point generation. Plot boundery points
            %as red dots.
            if app.ScanmodeButtonGroup.SelectedObject == app.ScanareaButton

                if height(app.existingPointPos) >= 3
                    app.microScanningPoints = UpdateScanningPoints(app);
                end
                if ~isempty(app.selectedPoints)
                    delete(app.selectedPoints);
                end
                app.selectedPoints = plot3(ax, app.existingPointPos(:,1), app.existingPointPos(:,2), app.existingPointPos(:,3), ...
                    '-o','Color','r','MarkerSize',3,...
                    'MarkerFaceColor','r');

                %If selected mode was "Scan selection of points", when there
                % is 1 or more scanning points clicked, enable the start of
                % a micro scan, and update the point's distance to the object
                % decided by the "distance to object spinner" value. Plot the
                % selected points as blue dots.
            elseif app.ScanmodeButtonGroup.SelectedObject == app.ScanselectionofpointsButton
                if height(app.existingPointPos) > 0
                    app.StartmicroscanButton.Enable = "on";
                    [LastPointPol(:,1),LastPointPol(:,2) , LastPointPol(:,3)] = cart2pol(app.existingPointPos(end,1), app.existingPointPos(end,2), app.existingPointPos(end,3));
                    LastPointPol(:,2) = LastPointPol(:,2) + app.DistancetoobjectSpinner.Value;
                    [app.existingPointPos(end,1), app.existingPointPos(end,2), app.existingPointPos(end,3)] = pol2cart(LastPointPol(1),LastPointPol(2) ,LastPointPol(3) );
                else
                    app.StartmicroscanButton.Enable = "off";
                end
                app.microScanningPoints = app.existingPointPos;
                if ~isempty(app.scanningPointPreviewPlot)
                    delete(app.scanningPointPreviewPlot);
                end
                app.scanningPointPreviewPlot = plot3(app.UIAxes, app.microScanningPoints(:, 1), app.microScanningPoints(:, 2), app.microScanningPoints(:, 3),'.','MarkerSize',10, 'Color','cyan');
            end
            txt = '';
        end

 
        function NewpointList = UpdateScanningPoints(app)
            %When in "Scan whole object" mode or "Scan area" mode this function
            %is called. The function calls the generateScanningPoints function
            %that generated *almost* evenly spaced points on the object based on
            %the value given by the PointDensitySlider.
            fig = app.UIFigure;
            d = uiprogressdlg(fig,'Title','Generating scanning points',...
                'Indeterminate','on');
            drawnow
            value = app.PointdensitySlider.Value;
            pointDistance = round(52-(value/2));
            pointList = generateScanningPoints(app, app.shape, pointDistance, app.surfaceInterpolation);
            [plottedPointsPol(:,1), plottedPointsPol(:,2), plottedPointsPol(:,3)] = cart2pol(pointList(:,1),pointList(:,2) , pointList(:,3));
            plottedPointsPol(:,2) = plottedPointsPol(:,2) + app.DistancetoobjectSpinner.Value;

            %If the scanning mode is "Scan area" only the points within the
            %selected area should be included. Here, the points are
            %controlled if they are inside the area by first sorting the
            %user marked boudery points to find the highest and lowest Z
            %values. The width is decided by the two most upper marked
            %points.
            if app.ScanmodeButtonGroup.SelectedObject == app.ScanareaButton

                if height(app.existingPointPos) >= 3
                    [cornersPol(:,1),cornersPol(:,2) , cornersPol(:,3)] = cart2pol(app.existingPointPos(:,1), app.existingPointPos(:,2), app.existingPointPos(:,3));
                    cornersSortedZ =  sortrows(cornersPol, 3, "descend");
                    topRow = cornersSortedZ(1:2,:);

                    topZ = max(cornersPol(:,3));
                    botZ = min(cornersPol(:,3));

                    %Selects all points that are under the highest Z value (of selected boundery points),
                    %discarding all points that are higher than the highest
                    %Z value.
                    pointsInArea = find(plottedPointsPol(:,3) <= topZ);
                    plottedPointsPol = plottedPointsPol(pointsInArea, :);

                    %Now, when all points are higher than the highest Z
                    %value are gone, select only those that higher than the
                    %lowest Z value. This leads to only having points where
                    %p < highest Z value & p > lowest Z value
                    pointsInArea = find(plottedPointsPol(:,3) >= botZ);
                    plottedPointsPol = plottedPointsPol(pointsInArea, :);

                    %All points that are outside the desired Z interval is
                    %now gone. Below, points within the desired theta's are
                    %selected. because theta goes up to 3.16 and then
                    %switches to -3.16, special cases where boundery points
                    %are on each side of the "sign switch" much be handeled.

                    %The if function checks if the boudnery points marking
                    %the width of the area is on oposite sides (negative and
                    %positive) Because boundery points can be marked in any
                    %order, the side where the distance is the shortest
                    %between the boundery points is the selected one where
                    %the generated points are saved.

                    %Below, "dPi" stands for the distance between the points
                    %over the "Pi side" of the object
                    % (the side where theta reaches pi and switches sign),
                    % while "d0" stands for the distance over the "0 side"
                    % of the object (the side where theta is 0)
                    if topRow(1,1) > 0 && topRow(2,1) < 0 || topRow(1,1) < 0 && topRow(2,1) > 0
                        dPi = abs((abs(topRow(1,1)) - pi) + (abs(topRow(2,1)) - pi));
                        d0 = abs((abs(topRow(1,1)) - 0) + (abs(topRow(2,1)) - 0));
                        %Depending on which side is shortest, select which
                        %boundery point should be max and min theta
                        if dPi < d0
                            maxTheta = min(topRow(:,1));
                            minTheta = max(topRow(:,1));

                            %Select the points that are within the thetas
                            pointsInArea1 = plottedPointsPol(:,1) <= maxTheta;
                            pointsInArea2 = plottedPointsPol(:,1) >= minTheta;
                            pointsInArea = find(pointsInArea1 | pointsInArea2);
                        else
                            maxTheta = max(topRow(:,1));
                            minTheta = min(topRow(:,1));

                            %Select the points that are within the thetas
                            pointsInArea1 = plottedPointsPol(:,1) <= maxTheta;
                            pointsInArea2 = plottedPointsPol(:,1) >= minTheta;
                            pointsInArea = find(pointsInArea1 & pointsInArea2);
                        end

                    else
                        maxTheta = max(topRow(:,1));
                        minTheta = min(topRow(:,1));

                        pointsInArea1 = plottedPointsPol(:,1) <= maxTheta;
                        pointsInArea2 = plottedPointsPol(:,1) >= minTheta;
                        pointsInArea = find(pointsInArea1 & pointsInArea2);

                    end

                    plottedPointsPol = plottedPointsPol(pointsInArea, :);


                end


            end
            %Points have been worked on in polar coordinate system,
            %transform it back to cartesian system for easier handeling and
            %plotting.
            [NewpointList(:,1), NewpointList(:,2), NewpointList(:,3)] = pol2cart(plottedPointsPol(:,1),plottedPointsPol(:,2) ,plottedPointsPol(:,3) );

            if ~isempty(app.scanningPointPreviewPlot)
                delete(app.scanningPointPreviewPlot);
            end
            %Plot the generated points
            app.scanningPointPreviewPlot = plot3(app.UIAxes, NewpointList(:, 1), NewpointList(:, 2), NewpointList(:, 3),'.','MarkerSize',10, 'Color','cyan');
            %Display the nr of points in the gui
            app.nPointsValueLabel.Text = string(height(NewpointList));
            app.StartmicroscanButton.Enable = "on";

            %close the popup
            close(d)


        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ScanmodelButton
        function ScanmodelButtonPushed(app, event)
            %Add paths
            addpath(genpath(pwd));
            
            %Start laser scan, receive a point cloud back
            Pn = scanBreast(app.connection);
            %Send a starting pose to SAY
            packAndSendPose([-200, 0, -10], [0, 90, 0], 0, 100, 0, app.connection);
            %Receive the SAY pose to control sync
            pos = ReceiveAndUnpackPose(app.connection);
            %Save the object points
            app.objPoints = Pn;
            YumiModelPos = pos;
            app.currentPose = YumiModelPos;

            %Plot object and SAY pos
            hold(app.UIAxes,'on')
            grid(app.UIAxes, 'on')
            shp = alphaShape(Pn(:,1),Pn(:,2),Pn(:,3),1);
            shp.Alpha = 200;
            %, 'FaceColor','green','FaceAlpha',0.6, 'EdgeColor','none' 
            %blue: [0 0.447 0.741] [0 0.489 0.811] 
            %pink: [0.976 0.524 0.634] [0.793 0.488 0.549]
            plot(shp, 'Parent', app.UIAxes, 'FaceAlpha',1, 'EdgeColor',[0 0.344 0.567] , 'FaceColor',[0 0.501 0.831]);
            app.wantedTCPplotPos = plot3(app.UIAxes, YumiModelPos(1), YumiModelPos(2), YumiModelPos(3),'.','MarkerSize',15, 'Color','Red');
            
            %Get the normals for each triangle in the object
            [app.centerPoints, app.normals] = getFaceNormals(app, shp);
       

            %Save both center points and vertex points as object points as
            %it gives more information about the object shape
            app.objPoints = [app.objPoints; app.centerPoints];
            

            %add all rows where t = 3.1416 also as the inverted t (-3.1416), to help
            %interpolation at sign switch when switching side in the
            %cylindrical coordinate system
            [t, r, z] = cart2pol(app.objPoints(:,1), app.objPoints(:,2), app.objPoints(:,3));
            indexPi = find(t>=3.1);
            for i = 1:height(indexPi)
                t(end+1) = -t(indexPi(i));
                r(end+1) = r(indexPi(i));
                z(end+1) = z(indexPi(i));
            end

            %get interpolation of surface
            app.surfaceInterpolation = scatteredInterpolant(t, z, r);
            updateTCPcoordAndAngleDisp(app);
            app.shape = shp;
            app.MicrowavescanningsettingsPanel.Enable = "on";
            app.ManualControlPanel.Enable = "on";
        end

        % Value changed function: CoordinateSystemDropDown
        function CoordinateSystemDropDownValueChanged(app, event)
            updateTCPcoordAndAngleManual(app);
            if app.CoordinateSystemDropDown.Value == "Carteasian"
                app.XLabel.Text = "X";
                app.YLabel.Text = "Y";
                app.ZLabel.Text = "Z";

                app.XLabel_2.Text = "X";
                app.YLabel_2.Text = "Y";
                app.ZLabel_2.Text = "Z";


            elseif app.CoordinateSystemDropDown.Value == "Cylindrical"
                app.XLabel.Text = "R";
                app.YLabel.Text = "θ";
                app.ZLabel.Text = "Z";

                app.XLabel_2.Text = "R";
                app.YLabel_2.Text = "θ";
                app.ZLabel_2.Text = "Z";

            elseif app.CoordinateSystemDropDown.Value == "Spherical"
                app.XLabel.Text = "Φ";
                app.YLabel.Text = "θ";
                app.ZLabel.Text = "ρ";

                app.XLabel_2.Text = "Φ";
                app.YLabel_2.Text = "θ";
                app.ZLabel_2.Text = "ρ";

            end


        end

        % Button pushed function: manualXRightBtn
        function manualXRightBtnPushed(app, event)

            if app.CoordinateSystemDropDown.Value == "Carteasian"
                newStep = app.wantedTCPplotPos.XData + app.StepsizeSpinner.Value;
                allowed = testIfStepAllowed(app, [newStep, app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData], app.waitingOnPos, app.shape);
                if allowed
                    app.wantedTCPplotPos.XData = newStep;
                    updateTCPcoordAndAngleManual(app);
                end

            elseif app.CoordinateSystemDropDown.Value == "Cylindrical"
                [theta, r, z] = cart2pol(app.wantedTCPplotPos.XData,app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData);
                [newStepX, newStepY, newStepZ] = pol2cart(theta, r + app.StepsizeSpinner.Value, z);
                allowed = testIfStepAllowed(app, [newStepX, newStepY, newStepZ], app.waitingOnPos, app.shape);
                if allowed
                    app.wantedTCPplotPos.XData = newStepX;
                    app.wantedTCPplotPos.YData = newStepY;
                    app.wantedTCPplotPos.ZData = newStepZ;
                    updateTCPcoordAndAngleManual(app);
                end
            elseif app.CoordinateSystemDropDown.Value == "Spherical"
                [theta, phi, rho] = cart2sph(app.wantedTCPplotPos.XData,app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData);
                [newStepX, newStepY, newStepZ] = sph2cart(theta, phi + app.StepsizeSpinner.Value, rho);
                allowed = testIfStepAllowed(app, [newStepX, newStepY, newStepZ], app.waitingOnPos, app.shape);
                if allowed
                    app.wantedTCPplotPos.XData = newStepX;
                    app.wantedTCPplotPos.YData = newStepY;
                    app.wantedTCPplotPos.ZData = newStepZ;
                    updateTCPcoordAndAngleManual(app);
                end

            end


        end

        % Button pushed function: ManualXLeftBtn
        function ManualXLeftBtnButtonPushed(app, event)
            if app.CoordinateSystemDropDown.Value == "Carteasian"
                newStep = app.wantedTCPplotPos.XData - app.StepsizeSpinner.Value;
                allowed = testIfStepAllowed(app, [newStep, app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData], app.waitingOnPos, app.shape);
                if allowed
                    app.wantedTCPplotPos.XData = newStep;
                    updateTCPcoordAndAngleManual(app);
                end

            elseif app.CoordinateSystemDropDown.Value == "Cylindrical"
                [theta, r, z] = cart2pol(app.wantedTCPplotPos.XData,app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData);
                [newStepX, newStepY, newStepZ] = pol2cart(theta, r - app.StepsizeSpinner.Value, z);
                allowed = testIfStepAllowed(app, [newStepX, newStepY, newStepZ], app.waitingOnPos, app.shape);
                if allowed
                    app.wantedTCPplotPos.XData = newStepX;
                    app.wantedTCPplotPos.YData = newStepY;
                    app.wantedTCPplotPos.ZData = newStepZ;
                    updateTCPcoordAndAngleManual(app);
                end

            elseif app.CoordinateSystemDropDown.Value == "Spherical"
                [theta, phi, rho] = cart2sph(app.wantedTCPplotPos.XData,app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData);  
                [newStepX, newStepY, newStepZ] = sph2cart(theta, phi - app.StepsizeSpinner.Value, rho);
                allowed = testIfStepAllowed(app, [newStepX, newStepY, newStepZ], app.waitingOnPos, app.shape);
                if allowed
                    app.wantedTCPplotPos.XData = newStepX;
                    app.wantedTCPplotPos.YData = newStepY;
                    app.wantedTCPplotPos.ZData = newStepZ;
                    updateTCPcoordAndAngleManual(app);
                end

            end
        end

        % Button pushed function: ManualZUpBtn
        function ManualZUpBtnButtonPushed(app, event)
            if app.CoordinateSystemDropDown.Value == "Carteasian"
                newStep = app.wantedTCPplotPos.ZData + app.StepsizeSpinner.Value;
                allowed = testIfStepAllowed(app, [app.wantedTCPplotPos.XData, app.wantedTCPplotPos.YData, newStep], app.waitingOnPos, app.shape);
                if allowed
                    app.wantedTCPplotPos.ZData = newStep;
                    updateTCPcoordAndAngleManual(app);
                end

            elseif app.CoordinateSystemDropDown.Value == "Cylindrical"
                [theta, r, z] = cart2pol(app.wantedTCPplotPos.XData,app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData);
                [newStepX, newStepY, newStepZ] = pol2cart(theta, r, z + app.StepsizeSpinner.Value);
                allowed = testIfStepAllowed(app, [newStepX, newStepY, newStepZ], app.waitingOnPos, app.shape);
                if allowed
                    app.wantedTCPplotPos.XData = newStepX;
                    app.wantedTCPplotPos.YData = newStepY;
                    app.wantedTCPplotPos.ZData = newStepZ;
                    updateTCPcoordAndAngleManual(app);
                end

            elseif app.CoordinateSystemDropDown.Value == "Spherical"
                [theta, phi, rho] = cart2sph(app.wantedTCPplotPos.XData,app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData);
                [newStepX, newStepY, newStepZ] = sph2cart(theta, phi, rho + app.StepsizeSpinner.Value);
                allowed = testIfStepAllowed(app, [newStepX, newStepY, newStepZ], app.waitingOnPos, app.shape);
                if allowed
                    app.wantedTCPplotPos.XData = newStepX;
                    app.wantedTCPplotPos.YData = newStepY;
                    app.wantedTCPplotPos.ZData = newStepZ;
                    updateTCPcoordAndAngleManual(app);
                end

            end
        end

        % Button pushed function: ManualZDownBtn
        function ManualZDownBtnButtonPushed(app, event)
            if app.CoordinateSystemDropDown.Value == "Carteasian"
                newStep = app.wantedTCPplotPos.ZData - app.StepsizeSpinner.Value;
                allowed = testIfStepAllowed(app, [app.wantedTCPplotPos.XData, app.wantedTCPplotPos.YData, newStep], app.waitingOnPos, app.shape);
                if allowed
                    app.wantedTCPplotPos.ZData = newStep;
                    updateTCPcoordAndAngleManual(app);
                end
            elseif app.CoordinateSystemDropDown.Value == "Cylindrical"
                [theta, r, z] = cart2pol(app.wantedTCPplotPos.XData,app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData);
                [newStepX, newStepY, newStepZ] = pol2cart(theta, r, z - app.StepsizeSpinner.Value);
                allowed = testIfStepAllowed(app, [newStepX, newStepY, newStepZ], app.waitingOnPos, app.shape);
                if allowed
                    app.wantedTCPplotPos.XData = newStepX;
                    app.wantedTCPplotPos.YData = newStepY;
                    app.wantedTCPplotPos.ZData = newStepZ;
                    updateTCPcoordAndAngleManual(app);
                end

            elseif app.CoordinateSystemDropDown.Value == "Spherical"
                [theta, phi, rho] = cart2sph(app.wantedTCPplotPos.XData,app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData);
                [newStepX, newStepY, newStepZ] = sph2cart(theta, phi, rho - app.StepsizeSpinner.Value);
                allowed = testIfStepAllowed(app, [newStepX, newStepY, newStepZ], app.waitingOnPos, app.shape);
                if allowed
                    app.wantedTCPplotPos.XData = newStepX;
                    app.wantedTCPplotPos.YData = newStepY;
                    app.wantedTCPplotPos.ZData = newStepZ;
                    updateTCPcoordAndAngleManual(app);
                end

            end
        end

        % Button pushed function: manualYRightBtn
        function manualYRightBtnButtonPushed(app, event)
            if app.CoordinateSystemDropDown.Value == "Carteasian"
                newStep = app.wantedTCPplotPos.YData + app.StepsizeSpinner.Value;
                allowed = testIfStepAllowed(app, [app.wantedTCPplotPos.XData, newStep, app.wantedTCPplotPos.ZData], app.waitingOnPos, app.shape);
                if allowed
                    app.wantedTCPplotPos.YData = newStep;
                    updateTCPcoordAndAngleManual(app);
                end

            elseif app.CoordinateSystemDropDown.Value == "Cylindrical"
                [theta, r, z] = cart2pol(app.wantedTCPplotPos.XData,app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData);
                [newStepX, newStepY, newStepZ] = pol2cart(theta + app.StepsizeSpinner.Value, r, z);
                allowed = testIfStepAllowed(app, [newStepX, newStepY, newStepZ], app.waitingOnPos, app.shape);
                if allowed
                    app.wantedTCPplotPos.XData = newStepX;
                    app.wantedTCPplotPos.YData = newStepY;
                    app.wantedTCPplotPos.ZData = newStepZ;
                    updateTCPcoordAndAngleManual(app);
                end

            elseif app.CoordinateSystemDropDown.Value == "Spherical"
                [theta, phi, rho] = cart2sph(app.wantedTCPplotPos.XData,app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData);  
                [newStepX, newStepY, newStepZ] = sph2cart(theta + app.StepsizeSpinner.Value, phi, rho);
                allowed = testIfStepAllowed(app, [newStepX, newStepY, newStepZ], app.waitingOnPos, app.shape);
                if allowed
                    app.wantedTCPplotPos.XData = newStepX;
                    app.wantedTCPplotPos.YData = newStepY;
                    app.wantedTCPplotPos.ZData = newStepZ;
                    updateTCPcoordAndAngleManual(app);
                end

            end
        end

        % Button pushed function: ManualYLeftBtn
        function ManualYLeftBtnButtonPushed(app, event)
             if app.CoordinateSystemDropDown.Value == "Carteasian"
                newStep = app.wantedTCPplotPos.YData - app.StepsizeSpinner.Value;
                allowed = testIfStepAllowed(app, [app.wantedTCPplotPos.XData, newStep, app.wantedTCPplotPos.ZData], app.waitingOnPos, app.shape);
                if allowed
                    app.wantedTCPplotPos.YData = newStep;
                    updateTCPcoordAndAngleManual(app);
                end
             elseif app.CoordinateSystemDropDown.Value == "Cylindrical"
                [theta, r, z] = cart2pol(app.wantedTCPplotPos.XData,app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData);
                [newStepX, newStepY, newStepZ] = pol2cart(theta - app.StepsizeSpinner.Value, r, z);
                allowed = testIfStepAllowed(app, [newStepX, newStepY, newStepZ], app.waitingOnPos, app.shape);
                if allowed
                    app.wantedTCPplotPos.XData = newStepX;
                    app.wantedTCPplotPos.YData = newStepY;
                    app.wantedTCPplotPos.ZData = newStepZ;
                    updateTCPcoordAndAngleManual(app);
                end

             elseif app.CoordinateSystemDropDown.Value == "Spherical"
                [theta, phi, rho] = cart2sph(app.wantedTCPplotPos.XData,app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData);  
                [newStepX, newStepY, newStepZ] = sph2cart(theta - app.StepsizeSpinner.Value, phi, rho);
                allowed = testIfStepAllowed(app, [newStepX, newStepY, newStepZ], app.waitingOnPos, app.shape);
                if allowed
                    app.wantedTCPplotPos.XData = newStepX;
                    app.wantedTCPplotPos.YData = newStepY;
                    app.wantedTCPplotPos.ZData = newStepZ;
                    updateTCPcoordAndAngleManual(app);
                end

            end
        end

        % Button pushed function: connecttorobotButton
        function connecttorobotButtonPushed(app, event)
               [connected, app.connection] = connectToYumi(app);
               %if connected
                %call need to be fixed:
                %   app.f = parfeval(backgroundPool,@getAndPlotTCPpos,0,1);
                

               %end
               app.ScanmodelButton.Enable = "on";
        end

        % Button pushed function: ClearallpointsButton
        function ClearallpointsButtonPushed(app, event)

            app.StartmicroscanButton.Enable = "off";
            app.microScanningPoints = [];
            if ~isempty(app.selectedPoints)
                delete(app.selectedPoints);
            end
            app.existingPointPos = [];
            set(app.UITable, 'Data', []);
            if ~isempty(app.scanningPointPreviewPlot)
                delete(app.scanningPointPreviewPlot);
            end
        end

        % Value changed function: SelectpointsButton
        function SelectpointsButtonValueChanged(app, event)
            selectingPoints = app.SelectpointsButton.Value;
            if selectingPoints
                app.dataCursor = datacursormode(app.UIFigure);
                app.dataCursor.removeAllDataCursors();
                app.dataCursor.Enable = 'on';
                app.dataCursor.SnapToDataVertex = 'off';
                app.dataCursor.UpdateFcn = @(hObj, event_)updateClickedPoints(app, hObj, event_ , app.UIAxes);
            else
                app.dataCursor.removeAllDataCursors();
                app.dataCursor.Enable = 'off';
                app.dataCursor.UpdateFcn = @(hObj, event_)makeDataTipInvisible(app, hObj, event_);
            end
        end

        % Selection changed function: ScanmodeButtonGroup
        function ScanmodeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ScanmodeButtonGroup.SelectedObject;
            app.microScanningPoints = [];
            app.StartmicroscanButton.Enable = "off";
            if ~isempty(app.selectedPoints)
                delete(app.selectedPoints);
            end
            set(app.UITable, 'Data', []);
            app.SelectpointsButton.Value = 0;

            app.existingPointPos = [];
            if ~isempty(app.scanningPointPreviewPlot)
                delete(app.scanningPointPreviewPlot);
            end


            if selectedButton == app.ScanwholeobjectButton

                app.UITable.Enable = "off";
                app.SelectpointsButton.Enable = "off";
                app.ClearallpointsButton.Enable = "off";
                app.ScanningpointselectionPanel.Enable = "off";
                app.ScanningpointgenerationPanel.Enable = "on";

                if ~isempty(app.dataCursor)
                    app.dataCursor.removeAllDataCursors();
                    app.dataCursor.Enable = 'off';
                    app.dataCursor.UpdateFcn = @(hObj, event_)makeDataTipInvisible(app, hObj, event_);


                end
            elseif selectedButton == app.ScanareaButton

                app.UITable.Enable = "on";
                app.SelectpointsButton.Enable = "on";
                app.ClearallpointsButton.Enable = "on";
                app.ScanningpointselectionPanel.Enable = "on";
                app.ScanningpointgenerationPanel.Enable = "on";
                app.ScanningpointselectionPanel.Title = "Boundery point selection";
            elseif selectedButton == app.ScanselectionofpointsButton

                app.UITable.Enable = "on";
                app.SelectpointsButton.Enable = "on";
                app.ClearallpointsButton.Enable = "on";
                app.ScanningpointselectionPanel.Enable = "on";
                app.ScanningpointgenerationPanel.Enable = "off";
                app.ScanningpointselectionPanel.Title = "Scanning point selection";

            end

        end

        % Value changed function: PointdensitySlider
        function PointdensitySliderValueChanged(app, event)
            app.microScanningPoints = UpdateScanningPoints(app);
        end

        % Value changed function: DistancetoobjectSpinner
        function DistancetoobjectSpinnerValueChanged(app, event)
            value = app.DistancetoobjectSpinner.Value;
            %if this is the first time the distance is changed:
            if isempty(app.prevDistanceValue)
                app.prevDistanceValue = 0;
            end
            %calculate the difference between new and old value:
            diffValue =  value  - app.prevDistanceValue;
            %If there is points to adjust; adjust them according to the
            %difference:
            if ~isempty(app.microScanningPoints)
                [plottedPointsPol(:,1), plottedPointsPol(:,2), plottedPointsPol(:,3)] = cart2pol(app.microScanningPoints(:,1),app.microScanningPoints(:,2) , app.microScanningPoints(:,3));

                plottedPointsPol(:,2) = plottedPointsPol(:,2) + diffValue;
                [NewpointList(:,1), NewpointList(:,2), NewpointList(:,3)] = pol2cart(plottedPointsPol(:,1),plottedPointsPol(:,2) ,plottedPointsPol(:,3) );

                %re-plot the points at the new distance:
                if ~isempty(app.scanningPointPreviewPlot)
                    delete(app.scanningPointPreviewPlot);
                end
                app.scanningPointPreviewPlot = plot3(app.UIAxes, NewpointList(:, 1), NewpointList(:, 2), NewpointList(:, 3),'.','MarkerSize',10, 'Color','cyan');
                app.microScanningPoints = NewpointList;
                app.prevDistanceValue = value;

            end


        end

        % Button pushed function: StartmicroscanButton
        function StartmicroscanButtonPushed(app, event)
            %assign space for rotation matrix:
            scanningPointTcpRotations = zeros(height(app.microScanningPoints), 3);
            rlScanPos = [app.microScanningPoints(:,1), app.microScanningPoints(:,2), app.microScanningPoints(:,3)];
            %rlScanPos = transPlotToRL(app, app.microScanningPoints(:,1), app.microScanningPoints(:,2), app.microScanningPoints(:,3), app.diffRLPlot);
            %get angles for each scanning point:
            for i=1:height(app.microScanningPoints)
                [angle_H, angle_P, angle_B, k] = calcAngles(app, app.microScanningPoints(i,:), app.centerPoints, app.normals);
                scanningPointTcpRotations(i, :) = [0, 90-angle_P, -angle_H];
                if ~isempty(angle_H)
                    %plot normal, origin from tcp point
                    if isempty(app.scanDirArrow) %if not plotted before, plot, otherwise update coordinates
                        app.scanDirArrow = quiver3(rlScanPos(i,1), rlScanPos(i,2), rlScanPos(i,3), app.normals(k,1), app.normals(k,2), app.normals(k,3), 'Color', 'cyan' , 'Parent', app.UIAxes, 'AutoScale','on', 'AutoScaleFactor',5, 'LineWidth',1.5, 'MaxHeadSize',20);
                    else
                        app.scanDirArrow.XData = rlScanPos(i,1);
                        app.scanDirArrow.YData = rlScanPos(i,2);
                        app.scanDirArrow.ZData = rlScanPos(i,3);

                        app.scanDirArrow.UData = app.normals(k,1);
                        app.scanDirArrow.VData = app.normals(k,2);
                        app.scanDirArrow.WData = app.normals(k,3);
                    end
                end
                writeToLog(app, "Send position to robot: " + num2str(rlScanPos(i,:)) + ", Rotation: " + num2str(scanningPointTcpRotations(i, :)));
                packAndSendPose(rlScanPos(i, :), scanningPointTcpRotations(i, :), 0, 100, 0, app.connection);
                pos = ReceiveAndUnpackPose(app.connection);
                app.wantedTCPplotPos.XData = pos(1);
                app.wantedTCPplotPos.YData = pos(2);
                app.wantedTCPplotPos.ZData = pos(3);
                updateTCPcoordAndAngleDisp(app);
                writeToLog(app, "Received position from robot:" + num2str(pos));
                pause(10);
            end
            %translate plot coordiantes of scanning points to real life
            %coordinates:
           
            %Send scanning points to robot:
            
        end

        % Button pushed function: microscanthispointButton
        function microscanthispointButtonPushed(app, event)
            %exportgraphics(app.UIFigure,'50Density.emf','ContentType','vector');
            fig = figure();
            h = copyUIAxes(app.UIAxes, fig);
        end

        % Button pushed function: laserscanthispointButton
        function laserscanthispointButtonPushed(app, event)
            SendToSmallRobot();
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
          MoveSmallRobotUp();
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            MoveSmallRobotDown();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Object reconstruction')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.AutomaticControlPanel = uipanel(app.GridLayout);
            app.AutomaticControlPanel.Title = 'Automatic Control';
            app.GridLayout2 = uigridlayout(app.AutomaticControlPanel);
            app.DmodelreconstructionPanel = uipanel(app.GridLayout2);
            app.DmodelreconstructionPanel.Title = '3D-model reconstruction';
            app.ScanmodelButton = uibutton(app.DmodelreconstructionPanel, 'push');
            app.ScanmodelButton.ButtonPushedFcn = createCallbackFcn(app, @ScanmodelButtonPushed, true);
            app.ScanmodelButton.Enable = 'off';
            app.ScanmodelButton.Text = 'Scan model';
            app.ScanningprotocolDropDownLabel = uilabel(app.DmodelreconstructionPanel);
            app.ScanningprotocolDropDownLabel.Text = 'Scanning protocol';
            app.ScanningprotocolDropDown = uidropdown(app.DmodelreconstructionPanel);
            app.connecttorobotButton = uibutton(app.DmodelreconstructionPanel, 'push');
            app.connecttorobotButton.ButtonPushedFcn = createCallbackFcn(app, @connecttorobotButtonPushed, true);
            app.connecttorobotButton.Text = 'connect to robot';
            app.EditField_4 = uieditfield(app.DmodelreconstructionPanel, 'text');
            app.EditField_4.Visible = 'off';
            app.EditField_5 = uieditfield(app.DmodelreconstructionPanel, 'text');
            app.EditField_5.Visible = 'off';
            app.EditField_6 = uieditfield(app.DmodelreconstructionPanel, 'text');
            app.EditField_6.Visible = 'off';
            app.MicrowavescanningsettingsPanel = uipanel(app.GridLayout2);
            app.MicrowavescanningsettingsPanel.Enable = 'off';
            app.MicrowavescanningsettingsPanel.Title = 'Microwave scanning settings';
            app.ScanmodeButtonGroup = uibuttongroup(app.MicrowavescanningsettingsPanel);
            app.ScanmodeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ScanmodeButtonGroupSelectionChanged, true);
            app.ScanmodeButtonGroup.Title = 'Scan mode';
            app.ScanwholeobjectButton = uitogglebutton(app.ScanmodeButtonGroup);
            app.ScanwholeobjectButton.Text = 'Scan whole object';
            app.ScanwholeobjectButton.Value = true;
            app.ScanareaButton = uitogglebutton(app.ScanmodeButtonGroup);
            app.ScanareaButton.Text = 'Scan area';
            app.ScanselectionofpointsButton = uitogglebutton(app.ScanmodeButtonGroup);
            app.ScanselectionofpointsButton.Text = 'Scan selection of points';
            app.DistancetoobjectSpinnerLabel = uilabel(app.ScanmodeButtonGroup);
            app.DistancetoobjectSpinnerLabel.Text = 'Distance to object';
            app.DistancetoobjectSpinner = uispinner(app.ScanmodeButtonGroup);
            app.DistancetoobjectSpinner.Limits = [0 Inf];
            app.DistancetoobjectSpinner.ValueChangedFcn = createCallbackFcn(app, @DistancetoobjectSpinnerValueChanged, true);
            app.EditField = uieditfield(app.ScanmodeButtonGroup, 'text');
            app.EditField.Visible = 'off';
            app.EditField_2 = uieditfield(app.ScanmodeButtonGroup, 'text');
            app.EditField_2.Visible = 'off';
            app.StartmicroscanButton = uibutton(app.MicrowavescanningsettingsPanel, 'push');
            app.StartmicroscanButton.ButtonPushedFcn = createCallbackFcn(app, @StartmicroscanButtonPushed, true);
            app.StartmicroscanButton.Enable = 'off';
            app.StartmicroscanButton.Text = 'Start micro scan';
            app.VNAPanel = uipanel(app.MicrowavescanningsettingsPanel);
            app.VNAPanel.Title = 'VNA';
            app.ScanningpointgenerationPanel = uipanel(app.MicrowavescanningsettingsPanel);
            app.ScanningpointgenerationPanel.Title = 'Scanning point generation';
            app.PointdensitySliderLabel = uilabel(app.ScanningpointgenerationPanel);
            app.PointdensitySliderLabel.Text = 'Point density';
            app.PointdensitySlider = uislider(app.ScanningpointgenerationPanel);
            app.PointdensitySlider.ValueChangedFcn = createCallbackFcn(app, @PointdensitySliderValueChanged, true);
            app.nPointsValueLabel = uilabel(app.ScanningpointgenerationPanel);
            app.nPointsValueLabel.Text = '0';
            app.scanningpointsLabel = uilabel(app.ScanningpointgenerationPanel);
            app.scanningpointsLabel.Text = ' scanning points';
            app.Label_4 = uilabel(app.ScanningpointgenerationPanel);
            app.Label_4.Text = '=';
            app.ScanningpointselectionPanel = uipanel(app.MicrowavescanningsettingsPanel);
            app.ScanningpointselectionPanel.Enable = 'off';
            app.ScanningpointselectionPanel.Title = 'Scanning point selection';
            app.UITable = uitable(app.ScanningpointselectionPanel);
            app.UITable.ColumnName = {'Point id'; 'X'; 'Y'; 'Z'};
            app.UITable.RowName = {};
            app.UITable.Enable = 'off';
            app.SelectpointsButton = uibutton(app.ScanningpointselectionPanel, 'state');
            app.SelectpointsButton.ValueChangedFcn = createCallbackFcn(app, @SelectpointsButtonValueChanged, true);
            app.SelectpointsButton.Enable = 'off';
            app.SelectpointsButton.Text = 'Select points';
            app.ClearallpointsButton = uibutton(app.ScanningpointselectionPanel, 'push');
            app.ClearallpointsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearallpointsButtonPushed, true);
            app.ClearallpointsButton.Enable = 'off';
            app.ClearallpointsButton.Text = 'Clear all points';
            app.EditField_3 = uieditfield(app.GridLayout2, 'text');
            app.EditField_3.Visible = 'off';
            app.ManualControlPanel = uipanel(app.GridLayout);
            app.ManualControlPanel.Title = 'Manual Control';
            app.GridLayout3 = uigridlayout(app.ManualControlPanel);
            app.microscanthispointButton = uibutton(app.GridLayout3, 'push');
            app.microscanthispointButton.ButtonPushedFcn = createCallbackFcn(app, @microscanthispointButtonPushed, true);
            app.microscanthispointButton.Text = 'micro scan this point';
            app.laserscanthispointButton = uibutton(app.GridLayout3, 'push');
            app.laserscanthispointButton.ButtonPushedFcn = createCallbackFcn(app, @laserscanthispointButtonPushed, true);
            app.laserscanthispointButton.Text = 'laser scan this point';
            app.SenderrobotarmPanel = uipanel(app.GridLayout3);
            app.SenderrobotarmPanel.Title = 'Sender robot arm';
            app.Button = uibutton(app.SenderrobotarmPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'icons', 'upArrow.png');
            app.Button.Text = '';
            app.Button_2 = uibutton(app.SenderrobotarmPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = fullfile(pathToMLAPP, 'icons', 'downArrow.png');
            app.Button_2.Text = '';
            app.Panel_2 = uipanel(app.GridLayout3);
            app.Panel_2.Title = 'Panel';
            app.ManualZUpBtn = uibutton(app.Panel_2, 'push');
            app.ManualZUpBtn.ButtonPushedFcn = createCallbackFcn(app, @ManualZUpBtnButtonPushed, true);
            app.ManualZUpBtn.Icon = fullfile(pathToMLAPP, 'icons', 'plus.png');
            app.ManualZUpBtn.Text = '';
            app.ManualXLeftBtn = uibutton(app.Panel_2, 'push');
            app.ManualXLeftBtn.ButtonPushedFcn = createCallbackFcn(app, @ManualXLeftBtnButtonPushed, true);
            app.ManualXLeftBtn.Icon = fullfile(pathToMLAPP, 'icons', 'minus.png');
            app.ManualXLeftBtn.Text = '';
            app.manualXRightBtn = uibutton(app.Panel_2, 'push');
            app.manualXRightBtn.ButtonPushedFcn = createCallbackFcn(app, @manualXRightBtnPushed, true);
            app.manualXRightBtn.Icon = fullfile(pathToMLAPP, 'icons', 'plus.png');
            app.manualXRightBtn.Text = '';
            app.ManualZDownBtn = uibutton(app.Panel_2, 'push');
            app.ManualZDownBtn.ButtonPushedFcn = createCallbackFcn(app, @ManualZDownBtnButtonPushed, true);
            app.ManualZDownBtn.Icon = fullfile(pathToMLAPP, 'icons', 'minus.png');
            app.ManualZDownBtn.Text = '';
            app.manualYRightBtn = uibutton(app.Panel_2, 'push');
            app.manualYRightBtn.ButtonPushedFcn = createCallbackFcn(app, @manualYRightBtnButtonPushed, true);
            app.manualYRightBtn.Icon = fullfile(pathToMLAPP, 'icons', 'plus.png');
            app.manualYRightBtn.Text = '';
            app.ManualYLeftBtn = uibutton(app.Panel_2, 'push');
            app.ManualYLeftBtn.ButtonPushedFcn = createCallbackFcn(app, @ManualYLeftBtnButtonPushed, true);
            app.ManualYLeftBtn.Icon = fullfile(pathToMLAPP, 'icons', 'minus.png');
            app.ManualYLeftBtn.Text = '';
            app.XLabel = uilabel(app.Panel_2);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.Panel_2);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.Panel_2);
            app.ZLabel.Text = 'Z';
            app.XLabel_2 = uilabel(app.Panel_2);
            app.XLabel_2.Text = 'X';
            app.YLabel_2 = uilabel(app.Panel_2);
            app.YLabel_2.Text = 'Y';
            app.Label = uilabel(app.Panel_2);
            app.Label.Text = '=';
            app.Label_2 = uilabel(app.Panel_2);
            app.Label_2.Text = '=';
            app.tcpCordX = uilabel(app.Panel_2);
            app.tcpCordX.Text = '0';
            app.tcpCordY = uilabel(app.Panel_2);
            app.tcpCordY.Text = '0';
            app.Panel = uipanel(app.Panel_2);
            app.ZLabel_2 = uilabel(app.Panel);
            app.ZLabel_2.Text = 'Z';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '=';
            app.tcpCordZ = uilabel(app.Panel);
            app.tcpCordZ.Text = '0';
            app.CoordinateSystemDropDownLabel = uilabel(app.Panel_2);
            app.CoordinateSystemDropDownLabel.Text = 'Coordinate System';
            app.CoordinateSystemDropDown = uidropdown(app.Panel_2);
            app.CoordinateSystemDropDown.Items = {'Carteasian', 'Cylindrical', 'Spherical'};
            app.CoordinateSystemDropDown.ValueChangedFcn = createCallbackFcn(app, @CoordinateSystemDropDownValueChanged, true);
            app.CoordinateSystemDropDown.Value = 'Carteasian';
            app.MinimumdistancetoobjectSpinnerLabel = uilabel(app.Panel_2);
            app.MinimumdistancetoobjectSpinnerLabel.Text = 'Minimum distance to object';
            app.MinimumdistancetoobjectSpinner = uispinner(app.Panel_2);
            app.MinimumdistancetoobjectSpinner.Step = 0.5;
            app.MinimumdistancetoobjectSpinner.Limits = [0 10000];
            app.StepsizeSpinnerLabel = uilabel(app.Panel_2);
            app.StepsizeSpinnerLabel.Text = 'Step size';
            app.StepsizeSpinner = uispinner(app.Panel_2);
            app.StepsizeSpinner.Step = 0.1;
            app.StepsizeSpinner.Limits = [0 100];
            app.StepsizeSpinner.Value = 0.1;
            app.TCPcoordinatesLabel = uilabel(app.Panel_2);
            app.TCPcoordinatesLabel.Text = 'TCP coordinates:';
            app.LogsTextAreaLabel = uilabel(app.GridLayout);
            app.LogsTextAreaLabel.Text = 'Logs';
            app.LogsTextArea = uitextarea(app.GridLayout);
            app.LogsTextArea.Value = {' '};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppMainLayout

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end