classdef TestLayout < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DriveawayfromobjectButton       matlab.ui.control.Button
        EmergencystopButton             matlab.ui.control.Button
        LogsTextArea                    matlab.ui.control.TextArea
        LogsTextAreaLabel               matlab.ui.control.Label
        ManualControlPanel              matlab.ui.container.Panel
        Panel                           matlab.ui.container.Panel
        tcpCordZ                        matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        ZLabel_2                        matlab.ui.control.Label
        TCPcoordinatesLabel             matlab.ui.control.Label
        tcpCordY                        matlab.ui.control.Label
        tcpCordX                        matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        YLabel_2                        matlab.ui.control.Label
        XLabel_2                        matlab.ui.control.Label
        StepsizeSpinner                 matlab.ui.control.Spinner
        StepsizeSpinnerLabel            matlab.ui.control.Label
        ZLabel                          matlab.ui.control.Label
        YLabel                          matlab.ui.control.Label
        XLabel                          matlab.ui.control.Label
        ManualYLeftBtn                  matlab.ui.control.Button
        manualYRightBtn                 matlab.ui.control.Button
        MinimumdistancetoobjectSpinner  matlab.ui.control.Spinner
        MinimumdistancetoobjectSpinnerLabel  matlab.ui.control.Label
        CoordinateSystemDropDown        matlab.ui.control.DropDown
        CoordinateSystemDropDownLabel   matlab.ui.control.Label
        ManualZDownBtn                  matlab.ui.control.Button
        manualXRightBtn                 matlab.ui.control.Button
        ManualXLeftBtn                  matlab.ui.control.Button
        ManualZUpBtn                    matlab.ui.control.Button
        AutomaticControlPanel           matlab.ui.container.Panel
        MicrowavescanningsettingsPanel  matlab.ui.container.Panel
        previewscanningpointsButton     matlab.ui.control.Button
        ScanningpointselectionPanel     matlab.ui.container.Panel
        ClearallpointsButton            matlab.ui.control.Button
        SelectpointsButton              matlab.ui.control.StateButton
        UITable                         matlab.ui.control.Table
        ScanningpointgenerationPanel    matlab.ui.container.Panel
        Label_4                         matlab.ui.control.Label
        scanningpointsLabel             matlab.ui.control.Label
        nPointsValueLabel               matlab.ui.control.Label
        PointdensitySlider              matlab.ui.control.Slider
        PointdensitySliderLabel         matlab.ui.control.Label
        VNAPanel                        matlab.ui.container.Panel
        StartmicroscanButton            matlab.ui.control.Button
        ScanmodeButtonGroup             matlab.ui.container.ButtonGroup
        DistancetoobjectSpinner         matlab.ui.control.Spinner
        DistancetoobjectSpinnerLabel    matlab.ui.control.Label
        ScanselectionofpointsButton     matlab.ui.control.ToggleButton
        ScanareaButton                  matlab.ui.control.ToggleButton
        ScanwholeobjectButton           matlab.ui.control.ToggleButton
        DmodelreconstructionPanel       matlab.ui.container.Panel
        connecttorobotButton            matlab.ui.control.Button
        DatahandelingButtonGroup        matlab.ui.container.ButtonGroup
        ComplimentdataButton            matlab.ui.control.ToggleButton
        ReplacedataButton               matlab.ui.control.ToggleButton
        ScanningprotocolDropDown        matlab.ui.control.DropDown
        ScanningprotocolDropDownLabel   matlab.ui.control.Label
        ScanmodelButton                 matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)

        objPoints;
        shape;
        wantedTCPplotPos;
        TCPplotPos;
        diffRLPlot;
        testingCoord;
        connection;
        currentPose;
        currentDeg;
        waitingOnPos;
        f;
        normals;
        centerPoints;
        dirArrow;
        dataCursor;
        areaBoundary;
        surfaceInterpolation;
        existingPointPos;
        selectedPoints; % Description
        generatedPoints % Description
        scanningPointPreviewPlot;
        testPlot;
    end
    
    
    methods (Access = private)
       
        function status = connectToYumi(app)
            %connect to server through ip (TCP)
            writeToLog(app, "Connect to robot: Starting connection through TCP.");
            app.connection = tcpclient('192.168.125.1',55000);
            if app.connection.Status == "open"
                status = 1;
                writeToLog(app, "Connect to robot: TCP connection to robot successful.");
            else
                status = 0;
                writeToLog(app, "Connect to robot: TCP connection to robot failed.");
            end
        end

        function [pos, rot] = ReceiveAndUnpackPose(app, t)
            app.waitingOnPos = 1;
            %wait until message is received
            noMsgReceived = 1;
            while noMsgReceived
                receivedMsg = char(read(t));
                noMsgReceived = isempty(receivedMsg);
                %pause(0.5);
            end
            %Handle message string so that it becoms seperate rot och pos arrays
            match = ["[","]"];
            pose = split(receivedMsg, match);
            pos = pose(2);
            pos = split(pos,",");
            pos = str2double(pos);

            while noMsgReceived
                receivedMsg = char(read(t));
                noMsgReceived = isempty(receivedMsg);
                %pause(0.5);
            end
            match = ["[","]"];
            pose = split(receivedMsg, match);
            rot = pose(2);
            rot = split(rot, ",");
            rot = str2double(rot);

            %eulerRad = quat2eul(rot');
            %eulerDeg = rad2deg(eulerRad);
            app.waitingOnPos = 0;
        end

       function packAndSendPose(app, pos, eulerDeg, t)
           %eulerRad = deg2rad(eulerDeg);
           %eulerDeg = [0,0,0];

           %rot = eul2quat(eulerRad)';

           %put together msg string
           sPos = string(pos);
           sRot = string(eulerDeg);
          

           %add formatting to pos string
           sPos = join(sPos,",");
           sPos = strcat("[",sPos,"]");

           %add formatting to rot string
           sRot = join(sRot,",");
           sRot = strcat("[",sRot,"]");
           write(t,sPos);
           write(t, sRot);

           %put pos and rot together
           %sendMsg = strcat(sPos,",",sRot);
           %sendMsg = strcat("[",sendMsg,"]");

           %send msg string
           %write(t,sendMsg)
       end


       function updateTCPcoordAndAngle(app)
           %Sends pose to robot if the connection is open and updates all
           %gui text displaying coordinate values
           [tTest, ~, zTest] = cart2pol(app.wantedTCPplotPos.XData,app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData);
            newPointR = app.surfaceInterpolation(tTest, zTest);
            [nx, ny,nz] = pol2cart(tTest, newPointR, zTest);
            %plot3(app.UIAxes, nx, ny, nz,'.','MarkerSize',10, 'Color','cyan');


           if ~isempty(app.wantedTCPplotPos)
               [angle_H, angle_P, angle_B, app.dirArrow] = calcAngles(app, app.wantedTCPplotPos, app.centerPoints, app.normals, app.dirArrow);

%                 if checkConnectionStatus(app)
%                     newRealPose = transPlotToRL(app, app.wantedTCPplotPos.XData, app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData, app.diffRLplot)';
%                     packAndSendPose(app, newRealPose, [angle_B, angle_H, angle_P], app.connection);
%                 end
           end


           if app.CoordinateSystemDropDown.Value == "Carteasian"
               app.tcpCordX.Text = string(app.wantedTCPplotPos.XData);
               app.tcpCordY.Text = string(app.wantedTCPplotPos.YData);
               app.tcpCordZ.Text = string(app.wantedTCPplotPos.ZData);

           elseif app.CoordinateSystemDropDown.Value == "Cylindrical"
               [theta, r, z] = cart2pol(app.wantedTCPplotPos.XData,app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData);
               app.tcpCordX.Text = string(r);
               app.tcpCordY.Text = string(theta);
               app.tcpCordZ.Text = string(z);

           elseif app.CoordinateSystemDropDown.Value == "Spherical"
               [theta, phi, rho] = cart2sph(app.wantedTCPplotPos.XData,app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData);
               app.tcpCordX.Text = string(phi);
               app.tcpCordY.Text = string(theta);
               app.tcpCordZ.Text = string(rho);


           end


       end

       function txt = updateClickedPoints(app, obj, event_obj, ax)
           pos = get(event_obj, 'Position');
           doesNotExist = true;
           oldTableData = get(app.UITable,'Data');
           existingPointPos = zeros(numel(app.selectedPoints), 3);
           for i=1:numel(app.selectedPoints)
               existingPointPos(i, :) = [app.selectedPoints(i).XData app.selectedPoints(i).YData app.selectedPoints(i).ZData];
               n = existingPointPos(i, :);
               if isequal(pos, n)
                   doesNotExist = false;
                   delete(app.selectedPoints(i));
                   app.selectedPoints(i) = [];
                   oldTableData(i, :) = [];
                   newTableData = oldTableData;
                   existingPointPos(i, :) = [];

                   break
               end

           end
           if doesNotExist
               point = plot3(ax, pos(1), pos(2), pos(3), ...
                   '-o','Color','r','MarkerSize',3,...
                   'MarkerFaceColor','r');
               app.selectedPoints = [app.selectedPoints point];
               newTableData = [oldTableData; [height(oldTableData)+1 pos]];
               existingPointPos = [existingPointPos ; pos];
           end

           set(app.UITable, 'Data', newTableData);
           app.existingPointPos = existingPointPos;
            if app.ScanmodeButtonGroup.SelectedObject == app.ScanareaButton

                if height(existingPointPos) >= 3
                    UpdateScanningPoints(app);
                end
            end
           txt = '';
       end

       function NewpointList = UpdateScanningPoints(app)
           fig = app.UIFigure;
           d = uiprogressdlg(fig,'Title','Generating scanning points',...
               'Indeterminate','on');
           drawnow
           value = app.PointdensitySlider.Value;
           pointDistance = round(52-(value/2));
           pointList = generateScanningPoints(app, app.shape, pointDistance, app.surfaceInterpolation);
           [plottedPointsPol(:,1), plottedPointsPol(:,2), plottedPointsPol(:,3)] = cart2pol(pointList(:,1),pointList(:,2) , pointList(:,3));
           %plottedPointsPol(:,2) = plottedPointsPol(:,2) + 1;
           %[plottedPointsPol(:,1), plottedPointsPol(:,2), plottedPointsPol(:,3)] = pol2cart(plottedPointsPol(:,1), plottedPointsPol(:,2), plottedPointsPol(:,3));
           if app.ScanmodeButtonGroup.SelectedObject == app.ScanareaButton

               if height(app.existingPointPos) >= 3
                   [cornersPol(:,1),cornersPol(:,2) , cornersPol(:,3)] = cart2pol(app.existingPointPos(:,1), app.existingPointPos(:,2), app.existingPointPos(:,3));
                   cornersSortedZ =  sortrows(cornersPol, 3, "descend");
                   topRow = cornersSortedZ(1:2,:);

                   topZ = max(cornersPol(:,3));
                   botZ = min(cornersPol(:,3));

                   pointsInArea = find(plottedPointsPol(:,3) <= topZ);
                   plottedPointsPol = plottedPointsPol(pointsInArea, :);

                   pointsInArea = find(plottedPointsPol(:,3) >= botZ);
                   plottedPointsPol = plottedPointsPol(pointsInArea, :);

                   if topRow(1,1) > 0 && topRow(2,1) < 0 || topRow(1,1) < 0 && topRow(2,1) > 0
                       dPi = abs((abs(topRow(1,1)) - pi) + (abs(topRow(2,1)) - pi));
                       d0 = abs((abs(topRow(1,1)) - 0) + (abs(topRow(2,1)) - 0));
                       if dPi < d0
                           maxTheta = min(topRow(:,1));
                           minTheta = max(topRow(:,1));

                           pointsInArea1 = plottedPointsPol(:,1) <= maxTheta;
                           pointsInArea2 = plottedPointsPol(:,1) >= minTheta;
                           pointsInArea = find(pointsInArea1 | pointsInArea2);
                       else
                           maxTheta = max(topRow(:,1));
                           minTheta = min(topRow(:,1));

                           pointsInArea1 = plottedPointsPol(:,1) <= maxTheta;
                           pointsInArea2 = plottedPointsPol(:,1) >= minTheta;
                           pointsInArea = find(pointsInArea1 & pointsInArea2);
                       end

                   else
                       maxTheta = max(topRow(:,1));
                       minTheta = min(topRow(:,1));

                       pointsInArea1 = plottedPointsPol(:,1) <= maxTheta;
                       pointsInArea2 = plottedPointsPol(:,1) >= minTheta;
                       pointsInArea = find(pointsInArea1 & pointsInArea2);

                   end

                   plottedPointsPol = plottedPointsPol(pointsInArea, :);
                   [NewpointList(:,1), NewpointList(:,2), NewpointList(:,3)] = pol2cart(plottedPointsPol(:,1),plottedPointsPol(:,2) ,plottedPointsPol(:,3) );

               end


           else
               NewpointList = pointList;

           end

           if ~isempty(app.scanningPointPreviewPlot)
               delete(app.scanningPointPreviewPlot);
           end
           %app.scanningPointPreviewPlot = plot3(app.UIAxes, plottedPointsPol(:, 1), plottedPointsPol(:, 2), plottedPointsPol(:, 3),'.','MarkerSize',10, 'Color','cyan');
           app.scanningPointPreviewPlot = plot3(app.UIAxes, NewpointList(:, 1), NewpointList(:, 2), NewpointList(:, 3),'.','MarkerSize',10, 'Color','cyan');
           app.nPointsValueLabel.Text = string(height(NewpointList));
            close(d)


        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ScanmodelButton
        function ScanmodelButtonPushed(app, event)
            a = load("D:\GitHub\MicroTomographyHT2022\PointsForGUI_2.mat");
            rng(0,'twister')

            %[pos, ~] = ReceiveAndUnpackPose(app, app.connection);
            pos = [-200,0,-40]';
            [x1,y1,z1] = ellipsoid(0,0,0,50,50,90);
            x1 = x1(:);
            y1 = y1(:);
            z1 = z1(:);
            p = [x1 y1 z1];
            p = unique(p,'rows');
            n = length(p);
            j = 1;
            %[p, app.diffRLPlot] = transRLtoPlot(app, p, app.diffRLPlot);
            %[YumiModelPos, app.diffRLPlot] = transRLtoPlot(app, pos', app.diffRLPlot);
            
            %app.currentDeg = eulerDeg;
            for i = 1:n
                if p(i,3) <= 0
                    Pn(j,:) = p(i, :);
                    j = j+1;

                end
            end
%             Pn = transRLtoPlot(app, Pn);

            a.meshEdgesMat = unique(a.meshEdgesMat,'rows');
            [Pn, app.diffRLPlot] = transRLtoPlot(app, a.meshEdgesMat, app.diffRLPlot);
            Pn = [Pn(:, 1) Pn(:,3), Pn(:,2)];
            app.objPoints = Pn;
            
            [YumiModelPos, app.diffRLPlot] = transRLtoPlot(app, pos', app.diffRLPlot);
            app.currentPose = YumiModelPos;


            hold(app.UIAxes,'on')
            %plot3(app.UIAxes, P(:,1),P(:,2),P(:,3),'.','MarkerSize',10);
            grid(app.UIAxes, 'on')
            %tetramesh(k, Pn, 'Parent', app.UIAxes);
            %plot3(app.UIAxes, Pn(:,1),Pn(:,2),Pn(:, 3),'.')
            shp = alphaShape(Pn(:,1),Pn(:,2),Pn(:,3),1);
            shp.Alpha = 200;
            %, 'FaceColor','green','FaceAlpha',0.6, 'EdgeColor','none'
            plot(shp, 'Parent', app.UIAxes, 'FaceAlpha',0.8, 'EdgeColor','none');
            app.wantedTCPplotPos = plot3(app.UIAxes, YumiModelPos(1), YumiModelPos(2), YumiModelPos(3),'.','MarkerSize',100, 'Color','Red');
            [app.centerPoints, app.normals] = getFaceNormals(app, shp);
            %interpolate surface for later estimation on scanning points
            app.objPoints = [app.objPoints; app.centerPoints];
            %plot3(app.UIAxes, app.objPoints(:,1),app.objPoints(:,2),app.objPoints(:, 3),'.')
            [t, r, z] = cart2pol(app.objPoints(:,1), app.objPoints(:,2), app.objPoints(:,3));

            %add all rows where t = 3.1416 also as the inverted t, to help
            %interpolation at sign switch when switching side in
            %cylindrical coordinate system
            indexPi = find(t>=3.1);
            for i = 1:height(indexPi)
                t(end+1) = -t(indexPi(i));
                r(end+1) = r(indexPi(i));
                z(end+1) = z(indexPi(i));
            end

            app.surfaceInterpolation = scatteredInterpolant(t, z, r);
            updateTCPcoordAndAngle(app);
            app.shape = shp;
            %app.generatedPoints = generateScanningPoints(app, app.objPoints);
        end

        % Value changed function: CoordinateSystemDropDown
        function CoordinateSystemDropDownValueChanged(app, event)
            updateTCPcoordAndAngle(app);
            if app.CoordinateSystemDropDown.Value == "Carteasian"
                app.XLabel.Text = "X";
                app.YLabel.Text = "Y";
                app.ZLabel.Text = "Z";

                app.XLabel_2.Text = "X";
                app.YLabel_2.Text = "Y";
                app.ZLabel_2.Text = "Z";


            elseif app.CoordinateSystemDropDown.Value == "Cylindrical"
                app.XLabel.Text = "R";
                app.YLabel.Text = "θ";
                app.ZLabel.Text = "Z";

                app.XLabel_2.Text = "R";
                app.YLabel_2.Text = "θ";
                app.ZLabel_2.Text = "Z";

            elseif app.CoordinateSystemDropDown.Value == "Spherical"
                app.XLabel.Text = "Φ";
                app.YLabel.Text = "θ";
                app.ZLabel.Text = "ρ";

                app.XLabel_2.Text = "Φ";
                app.YLabel_2.Text = "θ";
                app.ZLabel_2.Text = "ρ";

            end


        end

        % Button pushed function: manualXRightBtn
        function manualXRightBtnPushed(app, event)

            if app.CoordinateSystemDropDown.Value == "Carteasian"
                newStep = app.wantedTCPplotPos.XData + app.StepsizeSpinner.Value;
                allowed = app.testIfStepAllowed([newStep, app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData]);
                if allowed
                    app.wantedTCPplotPos.XData = newStep;
                    updateTCPcoordAndAngle(app);
                end

            elseif app.CoordinateSystemDropDown.Value == "Cylindrical"
                [theta, r, z] = cart2pol(app.wantedTCPplotPos.XData,app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData);
                [newStepX, newStepY, newStepZ] = pol2cart(theta, r + app.StepsizeSpinner.Value, z);
                allowed = app.testIfStepAllowed([newStepX, newStepY, newStepZ]);
                if allowed
                    app.wantedTCPplotPos.XData = newStepX;
                    app.wantedTCPplotPos.YData = newStepY;
                    app.wantedTCPplotPos.ZData = newStepZ;
                    updateTCPcoordAndAngle(app);
                end
            elseif app.CoordinateSystemDropDown.Value == "Spherical"
                [theta, phi, rho] = cart2sph(app.wantedTCPplotPos.XData,app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData);
                [newStepX, newStepY, newStepZ] = sph2cart(theta, phi + app.StepsizeSpinner.Value, rho);
                allowed = app.testIfStepAllowed([newStepX, newStepY, newStepZ]);
                if allowed
                    app.wantedTCPplotPos.XData = newStepX;
                    app.wantedTCPplotPos.YData = newStepY;
                    app.wantedTCPplotPos.ZData = newStepZ;
                    updateTCPcoordAndAngle(app);
                end

            end


        end

        % Button pushed function: ManualXLeftBtn
        function ManualXLeftBtnButtonPushed(app, event)
            if app.CoordinateSystemDropDown.Value == "Carteasian"
                newStep = app.wantedTCPplotPos.XData - app.StepsizeSpinner.Value;
                allowed = app.testIfStepAllowed([newStep, app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData]);
                if allowed
                    app.wantedTCPplotPos.XData = newStep;
                    updateTCPcoordAndAngle(app);
                end

            elseif app.CoordinateSystemDropDown.Value == "Cylindrical"
                [theta, r, z] = cart2pol(app.wantedTCPplotPos.XData,app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData);
                [newStepX, newStepY, newStepZ] = pol2cart(theta, r - app.StepsizeSpinner.Value, z);
                allowed = app.testIfStepAllowed([newStepX, newStepY, newStepZ]);
                if allowed
                    app.wantedTCPplotPos.XData = newStepX;
                    app.wantedTCPplotPos.YData = newStepY;
                    app.wantedTCPplotPos.ZData = newStepZ;
                    updateTCPcoordAndAngle(app);
                end

            elseif app.CoordinateSystemDropDown.Value == "Spherical"
                [theta, phi, rho] = cart2sph(app.wantedTCPplotPos.XData,app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData);  
                [newStepX, newStepY, newStepZ] = sph2cart(theta, phi - app.StepsizeSpinner.Value, rho);
                allowed = app.testIfStepAllowed([newStepX, newStepY, newStepZ]);
                if allowed
                    app.wantedTCPplotPos.XData = newStepX;
                    app.wantedTCPplotPos.YData = newStepY;
                    app.wantedTCPplotPos.ZData = newStepZ;
                    updateTCPcoordAndAngle(app);
                end

            end
        end

        % Button pushed function: ManualZUpBtn
        function ManualZUpBtnButtonPushed(app, event)
            if app.CoordinateSystemDropDown.Value == "Carteasian"
                newStep = app.wantedTCPplotPos.ZData + app.StepsizeSpinner.Value;
                allowed = testIfStepAllowed(app, [app.wantedTCPplotPos.XData, app.wantedTCPplotPos.YData, newStep], app.waitingOnPos, app.shape);
                if allowed
                    app.wantedTCPplotPos.ZData = newStep;
                    updateTCPcoordAndAngle(app);
                end

            elseif app.CoordinateSystemDropDown.Value == "Cylindrical"
                [theta, r, z] = cart2pol(app.wantedTCPplotPos.XData,app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData);
                [newStepX, newStepY, newStepZ] = pol2cart(theta, r, z + app.StepsizeSpinner.Value);
                allowed = testIfStepAllowed([newStepX, newStepY, newStepZ]);
                if allowed
                    app.wantedTCPplotPos.XData = newStepX;
                    app.wantedTCPplotPos.YData = newStepY;
                    app.wantedTCPplotPos.ZData = newStepZ;
                    updateTCPcoordAndAngle(app);
                end

            elseif app.CoordinateSystemDropDown.Value == "Spherical"
                [theta, phi, rho] = cart2sph(app.wantedTCPplotPos.XData,app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData);
                [newStepX, newStepY, newStepZ] = sph2cart(theta, phi, rho + app.StepsizeSpinner.Value);
                allowed = app.testIfStepAllowed([newStepX, newStepY, newStepZ]);
                if allowed
                    app.wantedTCPplotPos.XData = newStepX;
                    app.wantedTCPplotPos.YData = newStepY;
                    app.wantedTCPplotPos.ZData = newStepZ;
                    updateTCPcoordAndAngle(app);
                end

            end
        end

        % Button pushed function: ManualZDownBtn
        function ManualZDownBtnButtonPushed(app, event)
            if app.CoordinateSystemDropDown.Value == "Carteasian"
                newStep = app.wantedTCPplotPos.ZData - app.StepsizeSpinner.Value;
                allowed = app.testIfStepAllowed([app.wantedTCPplotPos.XData, app.wantedTCPplotPos.YData, newStep]);
                if allowed
                    app.wantedTCPplotPos.ZData = newStep;
                    updateTCPcoordAndAngle(app);
                end
            elseif app.CoordinateSystemDropDown.Value == "Cylindrical"
                [theta, r, z] = cart2pol(app.wantedTCPplotPos.XData,app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData);
                [newStepX, newStepY, newStepZ] = pol2cart(theta, r, z - app.StepsizeSpinner.Value);
                allowed = app.testIfStepAllowed([newStepX, newStepY, newStepZ]);
                if allowed
                    app.wantedTCPplotPos.XData = newStepX;
                    app.wantedTCPplotPos.YData = newStepY;
                    app.wantedTCPplotPos.ZData = newStepZ;
                    updateTCPcoordAndAngle(app);
                end

            elseif app.CoordinateSystemDropDown.Value == "Spherical"
                [theta, phi, rho] = cart2sph(app.wantedTCPplotPos.XData,app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData);
                [newStepX, newStepY, newStepZ] = sph2cart(theta, phi, rho - app.StepsizeSpinner.Value);
                allowed = app.testIfStepAllowed([newStepX, newStepY, newStepZ]);
                if allowed
                    app.wantedTCPplotPos.XData = newStepX;
                    app.wantedTCPplotPos.YData = newStepY;
                    app.wantedTCPplotPos.ZData = newStepZ;
                    updateTCPcoordAndAngle(app);
                end

            end
        end

        % Button pushed function: manualYRightBtn
        function manualYRightBtnButtonPushed(app, event)
            if app.CoordinateSystemDropDown.Value == "Carteasian"
                newStep = app.wantedTCPplotPos.YData + app.StepsizeSpinner.Value;
                allowed = app.testIfStepAllowed([app.wantedTCPplotPos.XData, newStep, app.wantedTCPplotPos.ZData]);
                if allowed
                    app.wantedTCPplotPos.YData = newStep;
                    updateTCPcoordAndAngle(app);
                end

            elseif app.CoordinateSystemDropDown.Value == "Cylindrical"
                [theta, r, z] = cart2pol(app.wantedTCPplotPos.XData,app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData);
                [newStepX, newStepY, newStepZ] = pol2cart(theta + app.StepsizeSpinner.Value, r, z);
                allowed = app.testIfStepAllowed([newStepX, newStepY, newStepZ]);
                if allowed
                    app.wantedTCPplotPos.XData = newStepX;
                    app.wantedTCPplotPos.YData = newStepY;
                    app.wantedTCPplotPos.ZData = newStepZ;
                    updateTCPcoordAndAngle(app);
                end

            elseif app.CoordinateSystemDropDown.Value == "Spherical"
                [theta, phi, rho] = cart2sph(app.wantedTCPplotPos.XData,app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData);  
                [newStepX, newStepY, newStepZ] = sph2cart(theta + app.StepsizeSpinner.Value, phi, rho);
                allowed = app.testIfStepAllowed([newStepX, newStepY, newStepZ]);
                if allowed
                    app.wantedTCPplotPos.XData = newStepX;
                    app.wantedTCPplotPos.YData = newStepY;
                    app.wantedTCPplotPos.ZData = newStepZ;
                    updateTCPcoordAndAngle(app);
                end

            end
        end

        % Button pushed function: ManualYLeftBtn
        function ManualYLeftBtnButtonPushed(app, event)
             if app.CoordinateSystemDropDown.Value == "Carteasian"
                newStep = app.wantedTCPplotPos.YData - app.StepsizeSpinner.Value;
                allowed = app.testIfStepAllowed([app.wantedTCPplotPos.XData, newStep, app.wantedTCPplotPos.ZData]);
                if allowed
                    app.wantedTCPplotPos.YData = newStep;
                    updateTCPcoordAndAngle(app);
                end
             elseif app.CoordinateSystemDropDown.Value == "Cylindrical"
                [theta, r, z] = cart2pol(app.wantedTCPplotPos.XData,app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData);
                [newStepX, newStepY, newStepZ] = pol2cart(theta - app.StepsizeSpinner.Value, r, z);
                allowed = app.testIfStepAllowed([newStepX, newStepY, newStepZ]);
                if allowed
                    app.wantedTCPplotPos.XData = newStepX;
                    app.wantedTCPplotPos.YData = newStepY;
                    app.wantedTCPplotPos.ZData = newStepZ;
                    updateTCPcoordAndAngle(app);
                end

             elseif app.CoordinateSystemDropDown.Value == "Spherical"
                [theta, phi, rho] = cart2sph(app.wantedTCPplotPos.XData,app.wantedTCPplotPos.YData, app.wantedTCPplotPos.ZData);  
                [newStepX, newStepY, newStepZ] = sph2cart(theta - app.StepsizeSpinner.Value, phi, rho);
                allowed = app.testIfStepAllowed([newStepX, newStepY, newStepZ]);
                if allowed
                    app.wantedTCPplotPos.XData = newStepX;
                    app.wantedTCPplotPos.YData = newStepY;
                    app.wantedTCPplotPos.ZData = newStepZ;
                    updateTCPcoordAndAngle(app);
                end

            end
        end

        % Button pushed function: DriveawayfromobjectButton
        function DriveawayfromobjectButtonPushed(app, event)
            if app.wantedTCPplotPos.XData > 0
                app.wantedTCPplotPos.XData = app.wantedTCPplotPos.XData + 50;
            else
                app.wantedTCPplotPos.XData = app.wantedTCPplotPos.XData - 50;
            end

            if app.wantedTCPplotPos.YData > 0
                app.wantedTCPplotPos.YData = app.wantedTCPplotPos.YData + 50;
            else
                app.wantedTCPplotPos.YData = app.wantedTCPplotPos.YData - 50;
            end

            updateTCPcoordAndAngle(app);
        end

        % Button pushed function: connecttorobotButton
        function connecttorobotButtonPushed(app, event)
               connected = connectToYumi(app);
               %if connected
                %call need to be fixed:
                %   app.f = parfeval(backgroundPool,@getAndPlotTCPpos,0,1);
                

               %end
        end

        % Callback function
        function SelectpointsButtonPushed(app, event)
            d = datacursormode(app.UIFigure);
            d.Enable = 'on';
            d.SnapToDataVertex = 'off';
            d.UpdateFcn = @(hObj, event, ax)updateClickedPoints(app, hObj, event, app.UIAxes, d);
        end

        % Button pushed function: ClearallpointsButton
        function ClearallpointsButtonPushed(app, event)
            for i=1:numel(app.selectedPoints)
                delete(app.selectedPoints(i));
            end
            app.selectedPoints = [];
            app.existingPointPos = [];
            set(app.UITable, 'Data', []);
            if ~isempty(app.scanningPointPreviewPlot)
                delete(app.scanningPointPreviewPlot);
            end
        end

        % Value changed function: SelectpointsButton
        function SelectpointsButtonValueChanged(app, event)
            selectingPoints = app.SelectpointsButton.Value;
            if selectingPoints
                app.dataCursor = datacursormode(app.UIFigure);
                app.dataCursor.removeAllDataCursors();
                app.dataCursor.Enable = 'on';
                app.dataCursor.SnapToDataVertex = 'off';
                app.dataCursor.UpdateFcn = @(hObj, event_)updateClickedPoints(app, hObj, event_ , app.UIAxes);
            else
                app.dataCursor.removeAllDataCursors();
                app.dataCursor.Enable = 'off';
                app.dataCursor.UpdateFcn = @(hObj, event_)makeDataTipInvisible(app, hObj, event_);
            end
        end

        % Selection changed function: ScanmodeButtonGroup
        function ScanmodeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ScanmodeButtonGroup.SelectedObject;
            if selectedButton == app.ScanwholeobjectButton

                app.UITable.Enable = "off";
                app.SelectpointsButton.Enable = "off";
                app.ClearallpointsButton.Enable = "off";
                app.ScanningpointselectionPanel.Enable = "off";
                app.ScanningpointgenerationPanel.Enable = "on";

                if ~isempty(app.dataCursor)
                    app.dataCursor.removeAllDataCursors();
                    app.dataCursor.Enable = 'off';
                    app.dataCursor.UpdateFcn = @(hObj, event_)makeDataTipInvisible(app, hObj, event_);

                    for i=1:numel(app.selectedPoints)
                        delete(app.selectedPoints(i));
                    end
                    app.selectedPoints = [];
                    set(app.UITable, 'Data', []);
                    app.SelectpointsButton.Value = 0;

                    app.existingPointPos = [];
                    if ~isempty(app.scanningPointPreviewPlot)
                        delete(app.scanningPointPreviewPlot);
                    end
                end


            elseif selectedButton == app.ScanareaButton

                app.UITable.Enable = "on";
                app.SelectpointsButton.Enable = "on";
                app.ClearallpointsButton.Enable = "on";
                app.ScanningpointselectionPanel.Enable = "on";
                app.ScanningpointgenerationPanel.Enable = "on";
                app.ScanningpointselectionPanel.Title = "Boundery point selection";
                app.existingPointPos = [];
                if ~isempty(app.scanningPointPreviewPlot)
                    delete(app.scanningPointPreviewPlot);
                end

            elseif selectedButton == app.ScanselectionofpointsButton

                app.UITable.Enable = "on";
                app.SelectpointsButton.Enable = "on";
                app.ClearallpointsButton.Enable = "on";
                app.ScanningpointselectionPanel.Enable = "on";
                app.ScanningpointgenerationPanel.Enable = "off";
                app.ScanningpointselectionPanel.Title = "Scanning point selection";
                 app.existingPointPos = [];
                    if ~isempty(app.scanningPointPreviewPlot)
                        delete(app.scanningPointPreviewPlot);
                    end
            end

        end

        % Value changed function: PointdensitySlider
        function PointdensitySliderValueChanged(app, event)
            x = UpdateScanningPoints(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Object reconstruction')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.AutomaticControlPanel = uipanel(app.UIFigure);
            app.AutomaticControlPanel.Title = 'Automatic Control';
            app.DmodelreconstructionPanel = uipanel(app.AutomaticControlPanel);
            app.DmodelreconstructionPanel.Title = '3D-model reconstruction';
            app.ScanmodelButton = uibutton(app.DmodelreconstructionPanel, 'push');
            app.ScanmodelButton.ButtonPushedFcn = createCallbackFcn(app, @ScanmodelButtonPushed, true);
            app.ScanmodelButton.Text = 'Scan model';
            app.ScanningprotocolDropDownLabel = uilabel(app.DmodelreconstructionPanel);
            app.ScanningprotocolDropDownLabel.Text = 'Scanning protocol';
            app.ScanningprotocolDropDown = uidropdown(app.DmodelreconstructionPanel);
            app.DatahandelingButtonGroup = uibuttongroup(app.DmodelreconstructionPanel);
            app.DatahandelingButtonGroup.Title = 'Data handeling';
            app.ReplacedataButton = uitogglebutton(app.DatahandelingButtonGroup);
            app.ReplacedataButton.Text = 'Replace data';
            app.ReplacedataButton.Value = true;
            app.ComplimentdataButton = uitogglebutton(app.DatahandelingButtonGroup);
            app.ComplimentdataButton.Text = 'Compliment data';
            app.connecttorobotButton = uibutton(app.DmodelreconstructionPanel, 'push');
            app.connecttorobotButton.ButtonPushedFcn = createCallbackFcn(app, @connecttorobotButtonPushed, true);
            app.connecttorobotButton.Text = 'connect to robot';
            app.MicrowavescanningsettingsPanel = uipanel(app.AutomaticControlPanel);
            app.MicrowavescanningsettingsPanel.Title = 'Microwave scanning settings';
            app.ScanmodeButtonGroup = uibuttongroup(app.MicrowavescanningsettingsPanel);
            app.ScanmodeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ScanmodeButtonGroupSelectionChanged, true);
            app.ScanmodeButtonGroup.Title = 'Scan mode';
            app.ScanwholeobjectButton = uitogglebutton(app.ScanmodeButtonGroup);
            app.ScanwholeobjectButton.Text = 'Scan whole object';
            app.ScanwholeobjectButton.Value = true;
            app.ScanareaButton = uitogglebutton(app.ScanmodeButtonGroup);
            app.ScanareaButton.Text = 'Scan area';
            app.ScanselectionofpointsButton = uitogglebutton(app.ScanmodeButtonGroup);
            app.ScanselectionofpointsButton.Text = 'Scan selection of points';
            app.DistancetoobjectSpinnerLabel = uilabel(app.ScanmodeButtonGroup);
            app.DistancetoobjectSpinnerLabel.Text = 'Distance to object';
            app.DistancetoobjectSpinner = uispinner(app.ScanmodeButtonGroup);
            app.StartmicroscanButton = uibutton(app.MicrowavescanningsettingsPanel, 'push');
            app.StartmicroscanButton.Text = 'Start micro scan';
            app.VNAPanel = uipanel(app.MicrowavescanningsettingsPanel);
            app.VNAPanel.Title = 'VNA';
            app.ScanningpointgenerationPanel = uipanel(app.MicrowavescanningsettingsPanel);
            app.ScanningpointgenerationPanel.Title = 'Scanning point generation';
            app.PointdensitySliderLabel = uilabel(app.ScanningpointgenerationPanel);
            app.PointdensitySliderLabel.Text = 'Point density';
            app.PointdensitySlider = uislider(app.ScanningpointgenerationPanel);
            app.PointdensitySlider.ValueChangedFcn = createCallbackFcn(app, @PointdensitySliderValueChanged, true);
            app.PointdensitySlider.Value = 50;
            app.nPointsValueLabel = uilabel(app.ScanningpointgenerationPanel);
            app.nPointsValueLabel.Text = '0';
            app.scanningpointsLabel = uilabel(app.ScanningpointgenerationPanel);
            app.scanningpointsLabel.Text = ' scanning points';
            app.Label_4 = uilabel(app.ScanningpointgenerationPanel);
            app.Label_4.Text = '=';
            app.ScanningpointselectionPanel = uipanel(app.MicrowavescanningsettingsPanel);
            app.ScanningpointselectionPanel.Enable = 'off';
            app.ScanningpointselectionPanel.Title = 'Scanning point selection';
            app.UITable = uitable(app.ScanningpointselectionPanel);
            app.UITable.ColumnName = {'Point id'; 'X'; 'Y'; 'Z'};
            app.UITable.RowName = {};
            app.UITable.Enable = 'off';
            app.SelectpointsButton = uibutton(app.ScanningpointselectionPanel, 'state');
            app.SelectpointsButton.ValueChangedFcn = createCallbackFcn(app, @SelectpointsButtonValueChanged, true);
            app.SelectpointsButton.Enable = 'off';
            app.SelectpointsButton.Text = 'Select points';
            app.ClearallpointsButton = uibutton(app.ScanningpointselectionPanel, 'push');
            app.ClearallpointsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearallpointsButtonPushed, true);
            app.ClearallpointsButton.Enable = 'off';
            app.ClearallpointsButton.Text = 'Clear all points';
            app.previewscanningpointsButton = uibutton(app.MicrowavescanningsettingsPanel, 'push');
            app.previewscanningpointsButton.Text = 'preview scanning points';
            app.ManualControlPanel = uipanel(app.UIFigure);
            app.ManualControlPanel.Title = 'Manual Control';
            app.ManualZUpBtn = uibutton(app.ManualControlPanel, 'push');
            app.ManualZUpBtn.ButtonPushedFcn = createCallbackFcn(app, @ManualZUpBtnButtonPushed, true);
            app.ManualZUpBtn.Icon = fullfile(pathToMLAPP, 'icons', 'plus.png');
            app.ManualZUpBtn.Text = '';
            app.ManualXLeftBtn = uibutton(app.ManualControlPanel, 'push');
            app.ManualXLeftBtn.ButtonPushedFcn = createCallbackFcn(app, @ManualXLeftBtnButtonPushed, true);
            app.ManualXLeftBtn.Icon = fullfile(pathToMLAPP, 'icons', 'minus.png');
            app.ManualXLeftBtn.Text = '';
            app.manualXRightBtn = uibutton(app.ManualControlPanel, 'push');
            app.manualXRightBtn.ButtonPushedFcn = createCallbackFcn(app, @manualXRightBtnPushed, true);
            app.manualXRightBtn.Icon = fullfile(pathToMLAPP, 'icons', 'plus.png');
            app.manualXRightBtn.Text = '';
            app.ManualZDownBtn = uibutton(app.ManualControlPanel, 'push');
            app.ManualZDownBtn.ButtonPushedFcn = createCallbackFcn(app, @ManualZDownBtnButtonPushed, true);
            app.ManualZDownBtn.Icon = fullfile(pathToMLAPP, 'icons', 'minus.png');
            app.ManualZDownBtn.Text = '';
            app.CoordinateSystemDropDownLabel = uilabel(app.ManualControlPanel);
            app.CoordinateSystemDropDownLabel.Text = 'Coordinate System';
            app.CoordinateSystemDropDown = uidropdown(app.ManualControlPanel);
            app.CoordinateSystemDropDown.Items = {'Carteasian', 'Cylindrical', 'Spherical'};
            app.CoordinateSystemDropDown.ValueChangedFcn = createCallbackFcn(app, @CoordinateSystemDropDownValueChanged, true);
            app.CoordinateSystemDropDown.Value = 'Carteasian';
            app.MinimumdistancetoobjectSpinnerLabel = uilabel(app.ManualControlPanel);
            app.MinimumdistancetoobjectSpinnerLabel.Text = 'Minimum distance to object';
            app.MinimumdistancetoobjectSpinner = uispinner(app.ManualControlPanel);
            app.MinimumdistancetoobjectSpinner.Step = 0.5;
            app.MinimumdistancetoobjectSpinner.Limits = [0 10000];
            app.manualYRightBtn = uibutton(app.ManualControlPanel, 'push');
            app.manualYRightBtn.ButtonPushedFcn = createCallbackFcn(app, @manualYRightBtnButtonPushed, true);
            app.manualYRightBtn.Icon = fullfile(pathToMLAPP, 'icons', 'plus.png');
            app.manualYRightBtn.Text = '';
            app.ManualYLeftBtn = uibutton(app.ManualControlPanel, 'push');
            app.ManualYLeftBtn.ButtonPushedFcn = createCallbackFcn(app, @ManualYLeftBtnButtonPushed, true);
            app.ManualYLeftBtn.Icon = fullfile(pathToMLAPP, 'icons', 'minus.png');
            app.ManualYLeftBtn.Text = '';
            app.XLabel = uilabel(app.ManualControlPanel);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.ManualControlPanel);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.ManualControlPanel);
            app.ZLabel.Text = 'Z';
            app.StepsizeSpinnerLabel = uilabel(app.ManualControlPanel);
            app.StepsizeSpinnerLabel.Text = 'Step size';
            app.StepsizeSpinner = uispinner(app.ManualControlPanel);
            app.StepsizeSpinner.Step = 0.1;
            app.StepsizeSpinner.Limits = [0 100];
            app.StepsizeSpinner.Value = 0.1;
            app.XLabel_2 = uilabel(app.ManualControlPanel);
            app.XLabel_2.Text = 'X';
            app.YLabel_2 = uilabel(app.ManualControlPanel);
            app.YLabel_2.Text = 'Y';
            app.Label = uilabel(app.ManualControlPanel);
            app.Label.Text = '=';
            app.Label_2 = uilabel(app.ManualControlPanel);
            app.Label_2.Text = '=';
            app.tcpCordX = uilabel(app.ManualControlPanel);
            app.tcpCordX.Text = '0';
            app.tcpCordY = uilabel(app.ManualControlPanel);
            app.tcpCordY.Text = '0';
            app.TCPcoordinatesLabel = uilabel(app.ManualControlPanel);
            app.TCPcoordinatesLabel.Text = 'TCP coordinates:';
            app.Panel = uipanel(app.ManualControlPanel);
            app.ZLabel_2 = uilabel(app.Panel);
            app.ZLabel_2.Text = 'Z';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '=';
            app.tcpCordZ = uilabel(app.Panel);
            app.tcpCordZ.Text = '0';
            app.LogsTextAreaLabel = uilabel(app.UIFigure);
            app.LogsTextAreaLabel.Text = 'Logs';
            app.LogsTextArea = uitextarea(app.UIFigure);
            app.LogsTextArea.Value = {' '};
            app.EmergencystopButton = uibutton(app.UIFigure, 'push');
            app.EmergencystopButton.Text = 'Emergency stop';
            app.DriveawayfromobjectButton = uibutton(app.UIFigure, 'push');
            app.DriveawayfromobjectButton.ButtonPushedFcn = createCallbackFcn(app, @DriveawayfromobjectButtonPushed, true);
            app.DriveawayfromobjectButton.Text = 'Drive away from object';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TestLayout

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end